classdef T2mapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T2mappUIFigure             matlab.ui.Figure
        TabGroup4                  matlab.ui.container.TabGroup
        SequenceparametersTab      matlab.ui.container.Tab
        DynamicsViewField          matlab.ui.control.NumericEditField
        EchoesLabel_2              matlab.ui.control.Label
        ESPEditField               matlab.ui.control.NumericEditField
        ESPmsLabel                 matlab.ui.control.Label
        DatafileEditField          matlab.ui.control.EditField
        DatafileEditFieldLabel     matlab.ui.control.Label
        AspectRatioViewField       matlab.ui.control.NumericEditField
        AspectratioLabel           matlab.ui.control.Label
        SLTViewField               matlab.ui.control.NumericEditField
        SlicethicknessmmLabel      matlab.ui.control.Label
        FOVViewField               matlab.ui.control.NumericEditField
        FOVmmEditFieldLabel        matlab.ui.control.Label
        EchoesViewField            matlab.ui.control.NumericEditField
        EchoesLabel                matlab.ui.control.Label
        SlicesViewField            matlab.ui.control.NumericEditField
        slicesLabel                matlab.ui.control.Label
        dimyViewField              matlab.ui.control.NumericEditField
        dimyEditFieldLabel         matlab.ui.control.Label
        dimxViewField              matlab.ui.control.NumericEditField
        dimxEditFieldLabel         matlab.ui.control.Label
        TE0EditField               matlab.ui.control.NumericEditField
        TE0msLabel                 matlab.ui.control.Label
        TRViewField                matlab.ui.control.NumericEditField
        TRmsEditFieldLabel         matlab.ui.control.Label
        TabGroup2                  matlab.ui.container.TabGroup
        RawimagesTab               matlab.ui.container.Tab
        LevelEditField             matlab.ui.control.NumericEditField
        LEditFieldLabel            matlab.ui.control.Label
        WindowEditField            matlab.ui.control.NumericEditField
        WEditFieldLabel            matlab.ui.control.Label
        AutoLWButton               matlab.ui.control.StateButton
        LevelSlider                matlab.ui.control.Slider
        WindowSlider               matlab.ui.control.Slider
        RawImage                   matlab.ui.control.Image
        SegmentationPanel          matlab.ui.container.Panel
        ThresholdEditField         matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel    matlab.ui.control.Label
        OpenButton                 matlab.ui.control.Button
        CloseButton                matlab.ui.control.Button
        DilateButton               matlab.ui.control.Button
        ErodeButton                matlab.ui.control.Button
        ThresholdSlider            matlab.ui.control.Slider
        ImageSelectionPanel        matlab.ui.container.Panel
        SlicesEditField            matlab.ui.control.NumericEditField
        SlicesSlider               matlab.ui.control.Slider
        EchoesEditField            matlab.ui.control.NumericEditField
        EchoesSlider               matlab.ui.control.Slider
        DynamicsEditField          matlab.ui.control.NumericEditField
        DynamicsSlider             matlab.ui.control.Slider
        DynamicsLabel              matlab.ui.control.Label
        EchoEditFieldLabel         matlab.ui.control.Label
        SliceEditFieldLabel        matlab.ui.control.Label
        ImageLabel                 matlab.ui.control.Label
        RawDataFig                 matlab.ui.control.UIAxes
        RecoparametersTab          matlab.ui.container.Tab
        CSregularizationLabel      matlab.ui.control.Label
        LLRxyzEditFieldLabel       matlab.ui.control.Label
        TVdynEditFieldLabel        matlab.ui.control.Label
        WVxyzEditField             matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel        matlab.ui.control.Label
        TVxyzEditField             matlab.ui.control.NumericEditField
        TVxyzEditFieldLabel        matlab.ui.control.Label
        LLRxyzEditField            matlab.ui.control.NumericEditField
        TVteEditField              matlab.ui.control.NumericEditField
        TVdynEditField             matlab.ui.control.NumericEditField
        TVteEditFieldLabel         matlab.ui.control.Label
        FilteringLabel             matlab.ui.control.Label
        DeRingLabel                matlab.ui.control.Label
        DeRingCheckBox             matlab.ui.control.CheckBox
        DeNoiseWindowEditField     matlab.ui.control.NumericEditField
        DeNoiseLabel               matlab.ui.control.Label
        DeNoiseCheckBox            matlab.ui.control.CheckBox
        FitparametersTab           matlab.ui.container.Tab
        DynamicsSelectionPanel     matlab.ui.container.Panel
        LastDynamicEditField       matlab.ui.control.NumericEditField
        LastsliceLabel_2           matlab.ui.control.Label
        FirstDynamicEditField      matlab.ui.control.NumericEditField
        FirstsliceLabel_2          matlab.ui.control.Label
        EchoselectionPanel         matlab.ui.container.Panel
        CheckBox_64                matlab.ui.control.CheckBox
        CheckBox_63                matlab.ui.control.CheckBox
        CheckBox_62                matlab.ui.control.CheckBox
        CheckBox_61                matlab.ui.control.CheckBox
        CheckBox_60                matlab.ui.control.CheckBox
        CheckBox_59                matlab.ui.control.CheckBox
        CheckBox_58                matlab.ui.control.CheckBox
        CheckBox_57                matlab.ui.control.CheckBox
        CheckBox_56                matlab.ui.control.CheckBox
        CheckBox_55                matlab.ui.control.CheckBox
        CheckBox_54                matlab.ui.control.CheckBox
        CheckBox_53                matlab.ui.control.CheckBox
        CheckBox_52                matlab.ui.control.CheckBox
        CheckBox_51                matlab.ui.control.CheckBox
        CheckBox_50                matlab.ui.control.CheckBox
        CheckBox_49                matlab.ui.control.CheckBox
        CheckBox_48                matlab.ui.control.CheckBox
        CheckBox_47                matlab.ui.control.CheckBox
        CheckBox_46                matlab.ui.control.CheckBox
        CheckBox_45                matlab.ui.control.CheckBox
        CheckBox_44                matlab.ui.control.CheckBox
        CheckBox_43                matlab.ui.control.CheckBox
        CheckBox_42                matlab.ui.control.CheckBox
        CheckBox_41                matlab.ui.control.CheckBox
        CheckBox_40                matlab.ui.control.CheckBox
        CheckBox_39                matlab.ui.control.CheckBox
        CheckBox_38                matlab.ui.control.CheckBox
        CheckBox_37                matlab.ui.control.CheckBox
        CheckBox_36                matlab.ui.control.CheckBox
        CheckBox_35                matlab.ui.control.CheckBox
        CheckBox_34                matlab.ui.control.CheckBox
        CheckBox_33                matlab.ui.control.CheckBox
        CheckBox_32                matlab.ui.control.CheckBox
        CheckBox_31                matlab.ui.control.CheckBox
        CheckBox_30                matlab.ui.control.CheckBox
        CheckBox_29                matlab.ui.control.CheckBox
        CheckBox_28                matlab.ui.control.CheckBox
        CheckBox_27                matlab.ui.control.CheckBox
        CheckBox_26                matlab.ui.control.CheckBox
        CheckBox_25                matlab.ui.control.CheckBox
        CheckBox_24                matlab.ui.control.CheckBox
        CheckBox_23                matlab.ui.control.CheckBox
        CheckBox_22                matlab.ui.control.CheckBox
        CheckBox_21                matlab.ui.control.CheckBox
        CheckBox_20                matlab.ui.control.CheckBox
        CheckBox_19                matlab.ui.control.CheckBox
        CheckBox_18                matlab.ui.control.CheckBox
        CheckBox_17                matlab.ui.control.CheckBox
        CheckBox_16                matlab.ui.control.CheckBox
        CheckBox_15                matlab.ui.control.CheckBox
        CheckBox_14                matlab.ui.control.CheckBox
        CheckBox_13                matlab.ui.control.CheckBox
        CheckBox_12                matlab.ui.control.CheckBox
        CheckBox_11                matlab.ui.control.CheckBox
        CheckBox_10                matlab.ui.control.CheckBox
        CheckBox_9                 matlab.ui.control.CheckBox
        CheckBox_8                 matlab.ui.control.CheckBox
        CheckBox_7                 matlab.ui.control.CheckBox
        CheckBox_6                 matlab.ui.control.CheckBox
        CheckBox_5                 matlab.ui.control.CheckBox
        CheckBox_4                 matlab.ui.control.CheckBox
        CheckBox_3                 matlab.ui.control.CheckBox
        CheckBox_2                 matlab.ui.control.CheckBox
        CheckBox_1                 matlab.ui.control.CheckBox
        GoodnessoffitPanel         matlab.ui.container.Panel
        Rsquare                    matlab.ui.control.NumericEditField
        MinRsquareLabel            matlab.ui.control.Label
        SliceselectionPanel        matlab.ui.container.Panel
        LastSliceEditField         matlab.ui.control.NumericEditField
        LastsliceLabel             matlab.ui.control.Label
        FirstSliceEditField        matlab.ui.control.NumericEditField
        FirstsliceLabel            matlab.ui.control.Label
        TabGroup3                  matlab.ui.container.TabGroup
        T2mapTab                   matlab.ui.container.Tab
        BrushEditField             matlab.ui.control.NumericEditField
        brushEditFieldLabel        matlab.ui.control.Label
        ExportRoiDataButton        matlab.ui.control.Button
        ROIaveragedLabel           matlab.ui.control.Label
        pixelValueLabel            matlab.ui.control.Label
        T2ViewField2               matlab.ui.control.EditField
        T2msLabel                  matlab.ui.control.Label
        T2ViewField                matlab.ui.control.EditField
        EraseButton                matlab.ui.control.Button
        ROIbutton                  matlab.ui.control.Button
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        T2Image                    matlab.ui.control.Image
        ColorMapButtonGroup        matlab.ui.container.ButtonGroup
        T2ScaleAutoButton          matlab.ui.control.Button
        T2ScaleMaxLabel            matlab.ui.control.Label
        T2ScaleEditField           matlab.ui.control.NumericEditField
        hotButton                  matlab.ui.control.RadioButton
        parulaButton               matlab.ui.control.RadioButton
        winterButton               matlab.ui.control.RadioButton
        autumnButton               matlab.ui.control.RadioButton
        grayButton                 matlab.ui.control.RadioButton
        ImageSelectionPanel2       matlab.ui.container.Panel
        T2MapSlicesEditField1      matlab.ui.control.NumericEditField
        T2MapSlicesSlider1         matlab.ui.control.Slider
        T2MapDynamicsEditField1    matlab.ui.control.NumericEditField
        T2MapDynamicsSlider1       matlab.ui.control.Slider
        SliceEditField_2Label_3    matlab.ui.control.Label
        SliceEditField_2Label      matlab.ui.control.Label
        T2MapLabel                 matlab.ui.control.Label
        T2linearPlot               matlab.ui.control.UIAxes
        T2logPlot                  matlab.ui.control.UIAxes
        T2MapFig                   matlab.ui.control.UIAxes
        M0R2Tab                    matlab.ui.container.Tab
        BrushEditField2            matlab.ui.control.NumericEditField
        brushEditField_2Label      matlab.ui.control.Label
        EraseButton2               matlab.ui.control.Button
        ROIbutton2                 matlab.ui.control.Button
        R2Image                    matlab.ui.control.Image
        M0Image                    matlab.ui.control.Image
        ColorMapButtonGroup3       matlab.ui.container.ButtonGroup
        hotButton3                 matlab.ui.control.RadioButton
        parulaButton3              matlab.ui.control.RadioButton
        winterButton3              matlab.ui.control.RadioButton
        autumnButton3              matlab.ui.control.RadioButton
        grayButton3                matlab.ui.control.RadioButton
        ColorMapButtonGroup2       matlab.ui.container.ButtonGroup
        hotButton2                 matlab.ui.control.RadioButton
        parulaButton2              matlab.ui.control.RadioButton
        winterButton2              matlab.ui.control.RadioButton
        autumnButton2              matlab.ui.control.RadioButton
        grayButton2                matlab.ui.control.RadioButton
        ImageSelectorPanel2        matlab.ui.container.Panel
        T2MapSlicesEditField2      matlab.ui.control.NumericEditField
        T2MapSlicesSlider2         matlab.ui.control.Slider
        T2MapDynamicsEditField2    matlab.ui.control.NumericEditField
        SliceEditField_2Label_4    matlab.ui.control.Label
        T2MapDynamicsSlider2       matlab.ui.control.Slider
        SliceEditField_2Label_2    matlab.ui.control.Label
        R2MapLabel                 matlab.ui.control.Label
        M0MapLabel                 matlab.ui.control.Label
        R2MapFig                   matlab.ui.control.UIAxes
        M0MapFig                   matlab.ui.control.UIAxes
        TabGroup1                  matlab.ui.container.TabGroup
        DashboardTab               matlab.ui.container.Tab
        EstimatedFitTimeViewField  matlab.ui.control.EditField
        EstimatedRegTimeViewField  matlab.ui.control.EditField
        AbortRegButton             matlab.ui.control.Button
        RegistrationDropDown       matlab.ui.control.DropDown
        CSrecoLabel                matlab.ui.control.Label
        CSrecoCheckBox             matlab.ui.control.CheckBox
        ModelBasedLabel            matlab.ui.control.Label
        MobaCheckBox               matlab.ui.control.CheckBox
        RegProgressLabel           matlab.ui.control.Label
        RegProgressGauge           matlab.ui.control.LinearGauge
        Lamp3                      matlab.ui.control.Lamp
        RegistrationButton         matlab.ui.control.Button
        AbortFitButton             matlab.ui.control.Button
        ExportGifButton            matlab.ui.control.Button
        Lamp7                      matlab.ui.control.Lamp
        FitProgressLabel           matlab.ui.control.Label
        ExportDicomButton          matlab.ui.control.Button
        ExitButton                 matlab.ui.control.Button
        Lamp6                      matlab.ui.control.Lamp
        FitProgressGauge           matlab.ui.control.LinearGauge
        Lamp5                      matlab.ui.control.Lamp
        T2FitButton                matlab.ui.control.Button
        Lamp4                      matlab.ui.control.Lamp
        ThresholdButton            matlab.ui.control.Button
        Lamp2                      matlab.ui.control.Lamp
        RecoButton                 matlab.ui.control.Button
        Lamp1                      matlab.ui.control.Lamp
        LoadMRDfileButton          matlab.ui.control.Button
        MRSImage                   matlab.ui.control.Image
        statusLabel                matlab.ui.control.Label
        StatusImage                matlab.ui.control.Image
        BackgroundBox              matlab.ui.control.TextArea
        MessageBox                 matlab.ui.control.TextArea
        TulpLogo                   matlab.ui.control.Image
        CreditsLabel               matlab.ui.control.Label
        T2T2mappingLabel           matlab.ui.control.Label
    end


    % --------------------------------------------------------------------------------------
    %
    %   T2 and T2* mapping tool
    %   
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   August 2022
    %
    %
    %
    %   Version 1.0; aug 2019
    %   - intial version
    %
    %   Version 1.1
    %   - Bug fixes
    %   - Added image registration option
    %
    %   Version 1.2
    %   - Fitting speed dramatically increased
    %
    %   Version 2.0; nov 2020
    %   - updated version
    %
    %   Version 2.1; nov 2020
    %   - T2* mapping of multi-gradient echo sequences
    %   
    %   Version 2.2; nov 2020
    %   - Corrected a bug in image dimensions and non-square aspect ratio
    %
    %   Version 2.3; May 2021
    %   - Multi-coil data
    %
    %   Version 2.4; July 2021
    %   - ROI and cross-hair T2 values
    %
    %   Version 2.6; Jan 2022
    %   - NIFTI ROI import
    %   - Various bug fixes
    %
    %   Version 3.0
    %   - Model-based reconstruction
    %   - Bug fix in dicom export
    %   - Various bug fixes
    %   - b-type scanner MGE import
    %   - Added dynamics
    %   - 3D T2*
    %   - DICOM export bug fix
    %
    %   Version 3.2
    %   - Able to handle data reconstructed with the P2ROUD app   
    %   - Tested some b-type scanner data
    %   - DICOM export, scaled output x 100 to prevent discretization of low T2(*) values
    %   - bug fixes with DICOM export
    %   - improved GIF, DICOM export
    %
    %   Version 3.3
    %   - Model based fitting on 2D registered images
    %   - ROI drawing in M0 images
    %   - Circular ROI drawing brush
    %   - Elastix image registration
    %
    %   Version 3.5
    %   - Unring, PCA denoising
    %   - Many bug fixes   
    %
    %   Version 3.6
    %   - Added compressed sensing reco parametersd
    %
    % --------------------------------------------------------------------------------------


    properties (Access = public)

        % App version
        appVersion = "3.6"          % App version
        bartVersion                 % Bart version
       
        % File variables
        appDir                      % Application directory
        oldDir                      % Starting directory
        mrdImportPath               % Dicom import path
        dicomExportPath             % Dicom export path
        gifExportPath               % gif export path
        roiExportPath               % roi data export path
        mrdFile                     % mrd data file
        dataFile                    % raw data file
        data                        % raw k-space data
        tag                         % file tag for export of dicom files
        
        % Flags
        validDataFlag               % valid data file true/false
        validRecoFlag               % valid reco true/false
        validRegFlag                % successful registration true/false
        validFitFlag                % valid fit true/false
        mrdFlag                     % standard mrd import path already defined true or false
        multiCoilFlag               % multi-coil data
        bartDetectedFlag            % bart toolbox available true/false
        data3dFlag                  % 3D data true or false
        p2roudFlag                  % data from proud or retrospective apps
        abortFitFlag                % abort fit false or true
        abortRegFlag                % abort registration false or true
        roiFlag                     % roi drawing true or false
               
        % Scan properties
        dimensions                  % image dimensions
        parameters                  % image parameters
        rprPars                     % rpr parameters
        ne                          % number of echoes
        dimx                        % nr points readout
        dimy                        % nt points phase encoding
        fov                         % field of view
        aspectRatio                 % field of view ratio
        sl                          % slice thickness
        ns                          % number of slices
        nd                          % number of dynamics
        tr                          % repetition time
        te                          % echo time / spacing
        tes                         % all echo times
        esp                         % echo spacing
        orientation                 % phase orientation
        nrCoils                     % number of RF receiver coils
        dataType                    % type of data, e.g. spin-echo or grad-echo
      
        % Images & plots
        images                      % reconstructed raw images (echo, x, y, slice, dynamic)
        threshold                   % segmentation threshold (1,ns,nd)
        mask                        % binary image with segmentation (x, y, slice, dynamic)
        norm                        % image normalization factor
        txt1                        % legend label     
        txt2                        % legend label  
        crossT2                     % cross-hair
        crossM0                     % cross-hair
        roiPoints                   % roi point coordinates
        roiT2                       % roi drawing object in T2 map
        roiM0                       % roi drawing object in M0 image
        structuringElement2D        % 2D structuring element
        structuringElement3D        % 3D structuring element
        
        % Fitting
        t2map                       % resulting t2map (x, y, slice, dynamic)
        t2cmap                      % T2 color mapping
        m0map                       % resulting m0map (x, y, slice, dynamic)
        m0cmap                      % M0 color mapping
        r2map                       % resulting R2 map
        r2cmap                      % R2 color mapping
        teSelection                 % TE selection array
        roiIntData                  % roi intensities as function of TE
        
        % Colors
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue
        grey                        % grey

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound
        
    end


    methods (Access = private)
        
        
        %------------------------------------------------
        %
        %  GUI layout
        %
        %------------------------------------------------


        % Traffic lights
        function SetLights(app,code)
            
            if code(1) == 0 app.Lamp1.Color = app.red; end %#ok<*SEPEX> 
            if code(2) == 0 app.Lamp2.Color = app.red; end
            if code(3) == 0 app.Lamp3.Color = app.red; end
            if code(4) == 0 app.Lamp4.Color = app.red; end
            if code(5) == 0 app.Lamp5.Color = app.red; end
            if code(6) == 0 app.Lamp6.Color = app.red; end
            if code(7) == 0 app.Lamp7.Color = app.red; end
                
            if code(1) == 1 app.Lamp1.Color = app.green; end
            if code(2) == 1 app.Lamp2.Color = app.green; end
            if code(3) == 1 app.Lamp3.Color = app.green; end
            if code(4) == 1 app.Lamp4.Color = app.green; end
            if code(5) == 1 app.Lamp5.Color = app.green; end
            if code(6) == 1 app.Lamp6.Color = app.green; end
            if code(7) == 1 app.Lamp7.Color = app.green; end
            
            drawnow;
            
        end
        
        
        % Buttons on/off
        function SetButtons(app,code)
        
            if code(1) == 0 app.LoadMRDfileButton.Enable = 'off';   else app.LoadMRDfileButton.Enable = 'on';   end
            if code(2) == 0 app.RecoButton.Enable = 'off';          else app.RecoButton.Enable = 'on';          end
            if code(3) == 0 
                app.RegistrationButton.Enable = 'off';  
                app.RegistrationDropDown.Enable = 'off';
            else 
                app.RegistrationButton.Enable = 'on';  
                app.RegistrationDropDown.Enable = 'on';
            end
            if code(4) == 0 app.ThresholdButton.Enable = 'off';     else app.ThresholdButton.Enable = 'on';     end
            if code(5) == 0 app.T2FitButton.Enable = 'off';         else app.T2FitButton.Enable = 'on';         end
            if code(6) == 0 app.ExportDicomButton.Enable = 'off';   else app.ExportDicomButton.Enable = 'on';   end
            if code(7) == 0 app.ExportGifButton.Enable = 'off';     else app.ExportGifButton.Enable = 'on';     end
            if code(8) == 0 app.ExitButton.Enable = 'off';          else app.ExitButton.Enable = 'on';          end
        
            drawnow;
            
        end
        
        
        % Switch fit parameters buttons on/off
        function SetFitButtons(app,code)
            
            if code == 0
                
                app.FirstSliceEditField.Enable = 'off';
                app.LastSliceEditField.Enable = 'off';
                app.FirstDynamicEditField.Enable = 'off';
                app.LastDynamicEditField.Enable = 'off';
                app.Rsquare.Enable = 'off';
                app.AbortFitButton.Enable = 'off';
                app.CSrecoCheckBox.Enable = 'off';
                app.CSrecoLabel.Enable = 'off';

                app.DeNoiseCheckBox.Enable = 'off';
                app.DeNoiseLabel.Enable = 'off';
                app.DeNoiseWindowEditField.Enable = 'off';
                app.DeRingCheckBox.Enable = 'off';
                app.DeRingLabel.Enable = 'off';

                app.WVxyzEditField.Enable = 'off';
                app.WVxyzEditFieldLabel.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.TVxyzEditFieldLabel.Enable = 'off';
                app.LLRxyzEditField.Enable = 'off';
                app.LLRxyzEditFieldLabel.Enable = 'off';
                app.TVteEditField.Enable = 'off';
                app.TVteEditFieldLabel.Enable = 'off';
                app.TVdynEditField.Enable = 'off';
                app.TVdynEditFieldLabel.Enable = 'off';
                
                app.MobaCheckBox.Enable = 'off';
                app.ModelBasedLabel.Enable = 'off';
                app.RegistrationDropDown.Enable = 'off';
                
                app.CheckBox_1.Enable = 'off';  app.CheckBox_2.Enable = 'off';  app.CheckBox_3.Enable = 'off';  app.CheckBox_4.Enable = 'off';
                app.CheckBox_5.Enable = 'off';  app.CheckBox_6.Enable = 'off';  app.CheckBox_7.Enable = 'off';  app.CheckBox_8.Enable = 'off';
                app.CheckBox_9.Enable = 'off';  app.CheckBox_10.Enable = 'off'; app.CheckBox_11.Enable = 'off'; app.CheckBox_12.Enable = 'off';
                app.CheckBox_13.Enable = 'off'; app.CheckBox_14.Enable = 'off'; app.CheckBox_15.Enable = 'off'; app.CheckBox_16.Enable = 'off';
                app.CheckBox_17.Enable = 'off'; app.CheckBox_18.Enable = 'off'; app.CheckBox_19.Enable = 'off'; app.CheckBox_20.Enable = 'off';
                app.CheckBox_21.Enable = 'off'; app.CheckBox_22.Enable = 'off'; app.CheckBox_23.Enable = 'off'; app.CheckBox_24.Enable = 'off';
                app.CheckBox_25.Enable = 'off'; app.CheckBox_26.Enable = 'off'; app.CheckBox_27.Enable = 'off'; app.CheckBox_28.Enable = 'off';
                app.CheckBox_29.Enable = 'off'; app.CheckBox_30.Enable = 'off'; app.CheckBox_31.Enable = 'off'; app.CheckBox_32.Enable = 'off';
                app.CheckBox_33.Enable = 'off'; app.CheckBox_34.Enable = 'off'; app.CheckBox_35.Enable = 'off'; app.CheckBox_36.Enable = 'off';
                app.CheckBox_37.Enable = 'off'; app.CheckBox_38.Enable = 'off'; app.CheckBox_39.Enable = 'off'; app.CheckBox_40.Enable = 'off';
                app.CheckBox_41.Enable = 'off'; app.CheckBox_42.Enable = 'off'; app.CheckBox_43.Enable = 'off'; app.CheckBox_44.Enable = 'off';
                app.CheckBox_45.Enable = 'off'; app.CheckBox_46.Enable = 'off'; app.CheckBox_47.Enable = 'off'; app.CheckBox_48.Enable = 'off';
                app.CheckBox_49.Enable = 'off'; app.CheckBox_50.Enable = 'off'; app.CheckBox_51.Enable = 'off'; app.CheckBox_52.Enable = 'off';
                app.CheckBox_53.Enable = 'off'; app.CheckBox_54.Enable = 'off'; app.CheckBox_55.Enable = 'off'; app.CheckBox_56.Enable = 'off';
                app.CheckBox_57.Enable = 'off'; app.CheckBox_58.Enable = 'off'; app.CheckBox_59.Enable = 'off'; app.CheckBox_60.Enable = 'off';
                app.CheckBox_61.Enable = 'off'; app.CheckBox_62.Enable = 'off'; app.CheckBox_63.Enable = 'off'; app.CheckBox_64.Enable = 'off';
             
            else
            
                if app.ns > 1
                    app.FirstSliceEditField.Enable = 'on';
                    app.LastSliceEditField.Enable = 'on';
                else
                    app.FirstSliceEditField.Enable = 'off';
                    app.LastSliceEditField.Enable = 'off';
                end

                if app.nd > 1
                    app.FirstDynamicEditField.Enable = 'on';
                    app.LastDynamicEditField.Enable = 'on';
                else
                    app.FirstDynamicEditField.Enable = 'off';
                    app.LastDynamicEditField.Enable = 'off';
                end

                app.Rsquare.Enable = 'on';
         
                if app.bartDetectedFlag && app.validDataFlag
                    app.CSrecoCheckBox.Enable = 'on';
                    app.CSrecoLabel.Enable = 'on';
                else
                    app.CSrecoCheckBox.Enable = 'off';
                    app.CSrecoLabel.Enable = 'off';
                end

                app.DeNoiseCheckBox.Enable = 'on';
                app.DeNoiseLabel.Enable = 'on';
                app.DeNoiseWindowEditField.Enable = 'on';
                app.DeRingCheckBox.Enable = 'on';
                app.DeRingLabel.Enable = 'on';

                app.WVxyzEditField.Enable = 'on';
                app.WVxyzEditFieldLabel.Enable = 'on';
                app.TVxyzEditField.Enable = 'on';
                app.TVxyzEditFieldLabel.Enable = 'on';
                app.LLRxyzEditField.Enable = 'on';
                app.LLRxyzEditFieldLabel.Enable = 'on';
                app.TVteEditField.Enable = 'on';
                app.TVteEditFieldLabel.Enable = 'on';
                app.TVdynEditField.Enable = 'on';
                app.TVdynEditFieldLabel.Enable = 'on';

                if app.bartDetectedFlag && app.validRecoFlag
                    app.MobaCheckBox.Enable = 'on';
                    app.ModelBasedLabel.Enable = 'on';
                else
                    app.MobaCheckBox.Enable = 'off';
                    app.ModelBasedLabel.Enable = 'off';
                end

                if app.validRecoFlag
                    app.RegistrationDropDown.Enable = 'on';
                else
                    app.RegistrationDropDown.Enable = 'off';
                end

                app.CheckBox_1.Enable = 'on';
                if app.ne > 1 app.CheckBox_2.Enable = 'on'; end;        if app.ne > 32 app.CheckBox_33.Enable = 'on'; end
                if app.ne > 2 app.CheckBox_3.Enable = 'on'; end;        if app.ne > 33 app.CheckBox_34.Enable = 'on'; end
                if app.ne > 3 app.CheckBox_4.Enable = 'on'; end;        if app.ne > 34 app.CheckBox_35.Enable = 'on'; end
                if app.ne > 4 app.CheckBox_5.Enable = 'on'; end;        if app.ne > 35 app.CheckBox_36.Enable = 'on'; end
                if app.ne > 5 app.CheckBox_6.Enable = 'on'; end;        if app.ne > 36 app.CheckBox_37.Enable = 'on'; end
                if app.ne > 6 app.CheckBox_7.Enable = 'on'; end;        if app.ne > 37 app.CheckBox_38.Enable = 'on'; end
                if app.ne > 7 app.CheckBox_8.Enable = 'on'; end;        if app.ne > 38 app.CheckBox_39.Enable = 'on'; end
                if app.ne > 8 app.CheckBox_9.Enable = 'on'; end;        if app.ne > 39 app.CheckBox_40.Enable = 'on'; end
                if app.ne > 9 app.CheckBox_10.Enable = 'on'; end;       if app.ne > 40 app.CheckBox_41.Enable = 'on'; end
                if app.ne > 10 app.CheckBox_11.Enable = 'on'; end;      if app.ne > 41 app.CheckBox_42.Enable = 'on'; end
                if app.ne > 11 app.CheckBox_12.Enable = 'on'; end;      if app.ne > 42 app.CheckBox_43.Enable = 'on'; end
                if app.ne > 12 app.CheckBox_13.Enable = 'on'; end;      if app.ne > 43 app.CheckBox_44.Enable = 'on'; end
                if app.ne > 13 app.CheckBox_14.Enable = 'on'; end;      if app.ne > 44 app.CheckBox_45.Enable = 'on'; end
                if app.ne > 14 app.CheckBox_15.Enable = 'on'; end;      if app.ne > 45 app.CheckBox_46.Enable = 'on'; end
                if app.ne > 15 app.CheckBox_16.Enable = 'on'; end;      if app.ne > 46 app.CheckBox_47.Enable = 'on'; end
                if app.ne > 16 app.CheckBox_17.Enable = 'on'; end;      if app.ne > 47 app.CheckBox_48.Enable = 'on'; end
                if app.ne > 17 app.CheckBox_18.Enable = 'on'; end;      if app.ne > 48 app.CheckBox_49.Enable = 'on'; end
                if app.ne > 18 app.CheckBox_19.Enable = 'on'; end;      if app.ne > 49 app.CheckBox_50.Enable = 'on'; end
                if app.ne > 19 app.CheckBox_20.Enable = 'on'; end;      if app.ne > 50 app.CheckBox_51.Enable = 'on'; end
                if app.ne > 20 app.CheckBox_21.Enable = 'on'; end;      if app.ne > 51 app.CheckBox_52.Enable = 'on'; end
                if app.ne > 21 app.CheckBox_22.Enable = 'on'; end;      if app.ne > 52 app.CheckBox_53.Enable = 'on'; end
                if app.ne > 22 app.CheckBox_23.Enable = 'on'; end;      if app.ne > 53 app.CheckBox_54.Enable = 'on'; end
                if app.ne > 23 app.CheckBox_24.Enable = 'on'; end;      if app.ne > 54 app.CheckBox_55.Enable = 'on'; end
                if app.ne > 24 app.CheckBox_25.Enable = 'on'; end;      if app.ne > 55 app.CheckBox_56.Enable = 'on'; end
                if app.ne > 25 app.CheckBox_26.Enable = 'on'; end;      if app.ne > 56 app.CheckBox_57.Enable = 'on'; end
                if app.ne > 26 app.CheckBox_27.Enable = 'on'; end;      if app.ne > 57 app.CheckBox_58.Enable = 'on'; end
                if app.ne > 27 app.CheckBox_28.Enable = 'on'; end;      if app.ne > 58 app.CheckBox_59.Enable = 'on'; end
                if app.ne > 28 app.CheckBox_29.Enable = 'on'; end;      if app.ne > 59 app.CheckBox_60.Enable = 'on'; end
                if app.ne > 29 app.CheckBox_30.Enable = 'on'; end;      if app.ne > 60 app.CheckBox_61.Enable = 'on'; end
                if app.ne > 30 app.CheckBox_31.Enable = 'on'; end;      if app.ne > 61 app.CheckBox_62.Enable = 'on'; end
                if app.ne > 31 app.CheckBox_32.Enable = 'on'; end;      if app.ne > 62 app.CheckBox_63.Enable = 'on'; end
                if app.ne > 63 app.CheckBox_64.Enable = 'on'; end
                
            end
                                   
            drawnow;
            
        end
        
        
        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)
            
            if code == 0
                app.SlicesEditField.Enable = 'off';
                app.SlicesSlider.Enable = 'off';
                app.EchoesEditField.Enable = 'off';
                app.EchoesSlider.Enable = 'off';
                app.DynamicsEditField.Enable = 'off';
                app.DynamicsSlider.Enable = 'off';
            else
                if app.ns >1
                    app.SlicesEditField.Enable = 'on';
                    app.SlicesSlider.Enable = 'on';
                else
                    app.SlicesEditField.Enable = 'off';
                    app.SlicesSlider.Enable = 'off';
                end
                if app.nd > 1
                    app.DynamicsEditField.Enable = 'on';
                    app.DynamicsSlider.Enable = 'on';
                else
                    app.DynamicsEditField.Enable = 'off';
                    app.DynamicsSlider.Enable = 'off';
                end
                app.EchoesEditField.Enable = 'on';
                app.EchoesSlider.Enable = 'on';
            end
            
            drawnow;
            
        end
        
        
        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)
            
            if code == 0
                app.ThresholdEditField.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.ErodeButton.Enable = 'off';
                app.DilateButton.Enable = 'off';
                app.CloseButton.Enable = 'off';
                app.OpenButton.Enable = 'off';
            else
                app.ThresholdEditField.Enable = 'on';
                app.ThresholdSlider.Enable = 'on';
                app.ErodeButton.Enable = 'on';
                app.DilateButton.Enable = 'on';
                app.CloseButton.Enable = 'on';
                app.OpenButton.Enable = 'on';
            end
            
            drawnow;
            
        end
        
        
        % Switch T2 map buttons on/off
        function SetMapButtons(app,code)
            
            if code == 0
                app.T2MapSlicesEditField1.Enable = 'off';
                app.T2MapSlicesSlider1.Enable = 'off';
                app.T2MapSlicesEditField2.Enable = 'off';
                app.T2MapSlicesSlider2.Enable = 'off';
                app.T2MapDynamicsEditField1.Enable = 'off';
                app.T2MapDynamicsEditField2.Enable = 'off';
                app.T2MapDynamicsSlider1.Enable = 'off';
                app.T2MapDynamicsSlider2.Enable = 'off';
                app.T2ScaleEditField.Enable = 'off';
                app.T2ScaleAutoButton.Enable = 'off';
                app.grayButton.Enable = 'off';
                app.autumnButton.Enable = 'off';
                app.winterButton.Enable = 'off';
                app.parulaButton.Enable = 'off';
                app.hotButton.Enable = 'off';
                app.grayButton2.Enable = 'off';
                app.autumnButton2.Enable = 'off';
                app.winterButton2.Enable = 'off';
                app.parulaButton2.Enable = 'off';
                app.hotButton2.Enable = 'off';
                app.grayButton3.Enable = 'off';
                app.autumnButton3.Enable = 'off';
                app.winterButton3.Enable = 'off';
                app.parulaButton3.Enable = 'off';
                app.hotButton3.Enable = 'off';
                app.AbortFitButton.Enable = 'off';
            else
                if app.ns > 1
                    app.T2MapSlicesEditField1.Enable = 'on';
                    app.T2MapSlicesSlider1.Enable = 'on';
                    app.T2MapSlicesEditField2.Enable = 'on';
                    app.T2MapSlicesSlider2.Enable = 'on';
                else
                    app.T2MapSlicesEditField1.Enable = 'off';
                    app.T2MapSlicesSlider1.Enable = 'off';
                    app.T2MapSlicesEditField2.Enable = 'off';
                    app.T2MapSlicesSlider2.Enable = 'off';
                end
                if app.nd > 1
                    app.T2MapDynamicsEditField1.Enable = 'on';
                    app.T2MapDynamicsEditField2.Enable = 'on';
                    app.T2MapDynamicsSlider1.Enable = 'on';
                    app.T2MapDynamicsSlider2.Enable = 'on';
                else
                    app.T2MapDynamicsEditField1.Enable = 'off';
                    app.T2MapDynamicsEditField2.Enable = 'off';
                    app.T2MapDynamicsSlider1.Enable = 'off';
                    app.T2MapDynamicsSlider2.Enable = 'off';
                end
                app.T2ScaleEditField.Enable = 'on';
                app.T2ScaleAutoButton.Enable = 'on';
                app.grayButton.Enable = 'on';
                app.autumnButton.Enable = 'on';
                app.winterButton.Enable = 'on';
                app.parulaButton.Enable = 'on';
                app.hotButton.Enable = 'on';
                app.grayButton2.Enable = 'on';
                app.autumnButton2.Enable = 'on';
                app.winterButton2.Enable = 'on';
                app.parulaButton2.Enable = 'on';
                app.hotButton2.Enable = 'on';
                app.grayButton3.Enable = 'on';
                app.autumnButton3.Enable = 'on';
                app.winterButton3.Enable = 'on';
                app.parulaButton3.Enable = 'on';
                app.hotButton3.Enable = 'on';
            end
           
            drawnow;
            
        end
        
        
        % Initialize the GUI
        function InitializeUI(app)
            
            % Message
            app.TextMessage('Initializing UI ...');

            % App version
            app.CreditsLabel.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ", app.appVersion);
            
            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green'; 
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.grey = '#787878';
            
            % Logos
            app.TulpLogo.ImageSource = 'AmsterdamUMC.gif';
            app.MRSImage.ImageSource = 'MRS.gif';

             % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');
            
            % Icons
            app.LoadMRDfileButton.Icon = 'disk.png';
            app.RecoButton.Icon = 'reconstruct.gif';
            app.RegistrationButton.Icon = 'sort.gif';
            app.ThresholdButton.Icon = 'threshold.gif';
            app.T2FitButton.Icon = 'exponential.gif';
            app.ExportDicomButton.Icon = 'disk.png';
            app.ExportGifButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.T2Image.ImageSource = 'square.gif';
            app.RawImage.ImageSource = 'square.gif';
            app.M0Image.ImageSource = 'square.gif';
            app.R2Image.ImageSource = 'square.gif';
            
            % set lights and buttons
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([1,0,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            
            % Set some plot properties
            app.RawDataFig.Toolbar.Visible = 'off';
            app.T2MapFig.Toolbar.Visible = 'off';
            app.M0MapFig.Toolbar.Visible = 'off';
            app.T2logPlot.Toolbar.Visible = 'off';
            app.T2linearPlot.Toolbar.Visible = 'off';
            app.t2cmap = parula;
            app.m0cmap = gray;
            app.r2cmap = parula;
            app.t2cmap(1,:) = [0 0 0];
            app.m0cmap(1,:) = [0 0 0];
            app.r2cmap(1,:) = [0 0 0];
            app.parulaButton.Value = 1;
            app.grayButton2.Value = 1;
            app.parulaButton3.Value = 1;
            
            % Switch off plot interactivity
            app.RawDataFig.Interactions = [];
            app.T2MapFig.Interactions = [];
            app.M0MapFig.Interactions = [];
            app.T2logPlot.Interactions = [];
            app.T2linearPlot.Interactions = [];
            
            % ROis and cross-hair
            app.roiFlag = false;
            app.roiPoints = [];
            delete(app.roiT2);
            delete(app.roiM0);
            
            drawnow;
            
        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------


        % Displays the mask in the raw image data
        function PlotImageFig(app)
          
            cla(app.RawDataFig);
            hold(app.RawDataFig,'on');
            
            % Define the colormap
            % Since it is not possible to have 2 colormaps in 1 figure, the
            % colormap is split in a grayscale part [0 1] and a red-color overlay part [1 2]
            cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
            cmap2 = [linspace(0,1,128)', zeros(128,2)];
            cmap = [cmap1;cmap2];
            cmap(cmap > 1) = 1;
                        
            % Background anatomy image
            im = squeeze(app.images(app.EchoesEditField.Value,:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value));
            if app.parameters.PHASE_ORIENTATION
                im = rot90(im,-1);
            end

            % Scale
            if app.AutoLWButton.Value == 1
                maxIm = max(im(:));
                app.WindowEditField.Value = maxIm;
                app.WindowSlider.Value = maxIm;
                app.LevelEditField.Value = maxIm/2;
                app.LevelSlider.Value = maxIm/2;
            end

            % Window and Level
            im = (1/app.WindowEditField.Value)*(im - app.LevelEditField.Value + app.WindowEditField.Value/2);
            im(im<0) = 0;
            im(im>0.9999) = 0.9999;

            % Plot the background image
            xlim(app.RawDataFig,[0, size(im,1) + 1]);
            ylim(app.RawDataFig,[0, size(im,2) + 1]);
            background = imshow(rot90(im),[0,2],'Colormap',cmap,'InitialMagnification','fit','Parent', app.RawDataFig); %#ok<NASGU> 
            
            % Overlay image with the mask
            imo = squeeze(2-app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value));
            alpha = squeeze(0.7-0.7*app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value));
            if app.parameters.PHASE_ORIENTATION
                imo = rot90(imo,-1);
                alpha = rot90(alpha,-1);
            end
            overlay = imshow(rot90(imo),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent', app.RawDataFig);
            set(overlay,'AlphaData',rot90(alpha));
            
            % Image aspect ratio
            aratio = app.AspectRatioViewField.Value*size(im,2)/size(im,1);
            if app.parameters.PHASE_ORIENTATION
                daspect(app.RawDataFig,[1/aratio 1 1]);
            else
                daspect(app.RawDataFig,[aratio 1 1]);
            end
            
            hold(app.RawDataFig,'off');
            drawnow;
            
            % Set some limits now
            maxim = app.images(1,:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value);
            app.ThresholdEditField.Limits = [0 max(maxim(:))+1];
            app.ThresholdEditField.Value = app.threshold(app.SlicesEditField.Value,app.DynamicsEditField.Value);                                                                       
            app.ThresholdSlider.Limits = [0 max(maxim(:))+1];
            app.ThresholdSlider.Value = app.threshold(app.SlicesEditField.Value,app.DynamicsEditField.Value);
                        
        end % PlotRawImages
        
        
        % Plots the maps for the current slice
        function PlotMaps(app)

            if app.validFitFlag

                hold(app.T2MapFig,'on');
                hold(app.M0MapFig,'on');
                hold(app.R2MapFig,'on');

                % Plot the T2 map
                im = squeeze(app.t2map(:,:,app.T2MapSlicesEditField1.Value,app.T2MapDynamicsEditField1.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.T2MapFig,[0, size(im,1) + 1]);
                ylim(app.T2MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 app.T2ScaleEditField.Value],'Colormap',app.t2cmap,'InitialMagnification','fit','Parent', app.T2MapFig);
                aratio = app.AspectRatioViewField.Value*size(im,2)/size(im,1);
                if app.parameters.PHASE_ORIENTATION
                    daspect(app.T2MapFig,[1/aratio 1 1]);
                else
                    daspect(app.T2MapFig,[aratio 1 1]);
                end
                colorbar(app.T2MapFig,'east','Ticks',[]);

                % ROI display if present
                if app.roiFlag && ~isempty(app.roiPoints)
                    app.roiPoints = unique(app.roiPoints,'rows');
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==app.T2MapSlicesEditField1.Value, :);
                    if app.parameters.PHASE_ORIENTATION
                        ysize = app.dimx;
                    else
                        ysize = app.dimy;
                    end
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);
                end

                % M0 map image
                im = squeeze(app.m0map(:,:,app.T2MapSlicesEditField1.Value,app.T2MapDynamicsEditField2.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end

                % Window and Level
                maxIm = max(im(:));
                window = maxIm;
                level = maxIm/2;
                im = (1/window)*(im - level + window/2);
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;

                % Plot the M0 map
                xlim(app.M0MapFig,[0, size(im,1) + 1]);
                ylim(app.M0MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 1],'Colormap',app.m0cmap,'InitialMagnification','fit','Parent', app.M0MapFig);
                if app.parameters.PHASE_ORIENTATION
                    daspect(app.M0MapFig,[1/aratio 1 1]);
                else
                    daspect(app.M0MapFig,[aratio 1 1]);
                end

                % Plot the R2 map
                im = squeeze(app.r2map(:,:,app.T2MapSlicesEditField1.Value,app.T2MapDynamicsEditField2.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.R2MapFig,[0, size(im,1) + 1]);
                ylim(app.R2MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[app.Rsquare.Value-0.01 1],'Colormap',app.r2cmap,'InitialMagnification','fit','Parent', app.R2MapFig);
                if app.parameters.PHASE_ORIENTATION
                    daspect(app.R2MapFig,[1/aratio 1 1]);
                else
                    daspect(app.R2MapFig,[aratio 1 1]);
                end
                colorbar(app.R2MapFig,'east','Ticks',[app.Rsquare.Value-0.01, 1]);
                delete(app.txt1);
                delete(app.txt2);
                app.txt1 = text(app.R2MapFig,0.8,0.95,'1.0','Units','normalized','Color',app.grey,'FontSize',14);
                app.txt2 = text(app.R2MapFig,0.8,0.05,num2str(app.Rsquare.Value),'Units','normalized','Color',app.grey,'FontSize',14);

                hold(app.T2MapFig,'off');
                hold(app.M0MapFig,'off');
                hold(app.R2MapFig,'off');
                drawnow;

            end
            
        end % PlotMaps


        % Draw a cross-hair on the T2 map
        function PlotCross(app,xcor,ycor,ysize)

            delete(app.crossT2);
            delete(app.crossM0);

            clr1 = 'r';
            if app.autumnButton.Value == 1 || app.hotButton.Value == 1
                clr1 = 'g';
            end

            clr2 = 'r';
            if app.autumnButton2.Value == 1 || app.hotButton2.Value == 1
                clr2 = 'g';
            end

            app.crossT2 = drawcrosshair(app.T2MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr1, 'LineWidth', 1);
            app.crossM0 = drawcrosshair(app.M0MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr2, 'LineWidth', 1);
        
        end % PlotCross


        % Draw points on the T2 map
        function PlotPoint(app,xcor,ycor,ysize)

            clr1 = 'r';
            if app.autumnButton.Value == 1 || app.hotButton.Value == 1
                clr1 = 'g';
            end

            clr2 = 'r';
            if app.autumnButton2.Value == 1 || app.hotButton2.Value == 1
                clr2 = 'g';
            end

            hold(app.T2MapFig,'on');
            delete(app.roiT2);
            app.roiT2 = plot(app.T2MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr1, 'MarkerFaceColor', clr1);
            hold(app.T2MapFig,'off');

            hold(app.M0MapFig,'on');
            delete(app.roiM0);
            app.roiM0 = plot(app.M0MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr2, 'MarkerFaceColor', clr2);
            hold(app.M0MapFig,'off');

        end % PlotPoint


        % Calculate and show T2 values of ROI
        function CalculateRoiT2(app)

            % ysize needed
            if app.parameters.PHASE_ORIENTATION
                ysize = app.dimx;
            else
                ysize = app.dimy;
            end

            for i = 1:size(app.roiPoints,1)
                if app.parameters.PHASE_ORIENTATION
                    t2values(i) = app.t2map(ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.T2MapDynamicsEditField1.Value);%#ok<AGROW> 
                    m0values(i) = app.m0map(ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.T2MapDynamicsEditField1.Value);%#ok<AGROW> 
                    rawdatas(i,:) = app.images(:,ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.T2MapDynamicsEditField1.Value)';%#ok<AGROW> 
                else
                    t2values(i) = app.t2map(app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.T2MapDynamicsEditField1.Value);%#ok<AGROW> 
                    m0values(i) = app.m0map(app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.T2MapDynamicsEditField1.Value);%#ok<AGROW> 
                    rawdatas(i,:) = app.images(:,app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.T2MapDynamicsEditField1.Value)';%#ok<AGROW> 
                end
            end

            % Averages in ROI or cross-hair value
            r2values = 1./t2values;
            r2values = nonzeros(r2values);
            r2values(isnan(r2values)) = [];
            r2values(isinf(r2values)) = [];
            t2value = 1/mean(r2values);                         % T2 = 1 / mean(R2 values in pixels)
            stdt2value = t2value*std(r2values)/mean(r2values);  % Error propagation with division
            if size(rawdatas,1)>1
                rawdatas(t2values==0,:) = nan;
                app.roiIntData = mean(rawdatas,'omitnan');      % Trick to exclude ROI pixels in background
            else
                app.roiIntData = rawdatas;
            end

            % TE selection
            app.MakeTeArray;
            nrTEs = length(find(app.teSelection(1:app.ne) == 1));

            % Do a fit of the ROI-averaged pixel intensities
            if nrTEs>1
                [m0roi,t2roi,~] = dotheT2fitSingle(app.roiIntData, app.tes, app.Rsquare.Value, app.teSelection);
            else
                t2roi = 0;
            end

            % Plot the results of the fits in the chosen pixel
            app.PlotFits(app.tes, app.roiIntData, t2value, t2roi, stdt2value, m0roi);

        end % CalculateRoiT2


        % Plots the fitresults in the 2 fit-windows
        function PlotFits(app, echotimes, data, t2, t2roi, stdt2, m0)
            
          if app.validRecoFlag  
              
            cla(app.T2linearPlot);
            cla(app.T2logPlot);
            hold(app.T2linearPlot,'on');
            hold(app.T2logPlot,'on');
            
            % Colormap for datapoints
            cmap = [hex2rgb(app.red) ; hex2rgb(app.blue)];
            tecolorflag = cmap(app.teSelection + 1,:);
            tecolorflag = tecolorflag(1:app.ne,:);
            
            if (t2roi > 0) && (m0 > 0)
                
                % fit
                a = 0 : 0.25: max(echotimes)+5;
                s = m0*exp(-a./t2roi);
                
                % plot linear
                scatter(app.T2linearPlot,app.tes,data,[],tecolorflag,'filled');
                plot(app.T2linearPlot,a,s);
                xlim(app.T2linearPlot,[0, max(echotimes)+5]);
                ylim(app.T2linearPlot,[0, 1.1*max(s)]);
               
                % plot log
                scatter(app.T2logPlot,app.tes,log(data),[],tecolorflag,'filled');
                plot(app.T2logPlot,a,log(s));
                xlim(app.T2logPlot,[0, max(echotimes)+5]);
                ylim(app.T2logPlot,[0.99*min(log(s)), 1.01*max(log(s))]);
            
                % show T2 value in app
                if stdt2 > 0
                    app.T2ViewField.Value = [num2str(t2,'% 10.1f'),' ',char(177),' ',num2str(stdt2,'% 10.1f')];
                else
                    app.T2ViewField.Value = num2str(t2,'% 10.1f');
                end

                app.T2ViewField2.Value = num2str(t2roi,'% 10.1f');
            
            else

                app.T2ViewField.Value = " ";
                app.T2ViewField2.Value = " ";

            end
            
            hold(app.T2linearPlot,'off');
            hold(app.T2logPlot,'off');
            
          end
           
        end % PlotFits


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------


        % Sets global parameters
        function SetParameters(app)
           
            % Message
            app.TextMessage('Setting scan parameters ...');

            % sequence parameters
            app.ne = app.parameters.NO_ECHOES;
            app.dimx = app.parameters.NO_SAMPLES;
            app.dimy = app.parameters.NO_VIEWS;
            app.fov = app.parameters.FOV;
            if app.data3dFlag
                app.ns = app.parameters.NO_VIEWS_2;
            else
                app.ns = app.parameters.NO_SLICES;
            end
            if isfield(app.parameters,"EXPERIMENT_ARRAY")
                app.nd = app.parameters.EXPERIMENT_ARRAY;
            else
                app.nd = 1;
            end
            app.tr = app.parameters.tr;
            app.te = app.parameters.te;
            app.esp = app.parameters.te;
            if app.data3dFlag
                app.sl = app.parameters.SLICE_THICKNESS/app.ns;
            else
                app.sl = app.parameters.SLICE_THICKNESS;
            end
            app.tes = app.te + app.esp*linspace(0,app.ne-1,app.ne)'; % echo times
            if strcmp(app.parameters.scanner,"b-type")
                app.tes = app.parameters.echotimes;
                app.esp = app.parameters.echotimes(2)-app.parameters.echotimes(1);
            end
            app.TextMessage(strcat('Echo times = ',{' '},num2str(app.tes')));

            % Total acquisition time in seconds
            if isfield(app.rprPars,"TotalAcquisitionTime")
                app.parameters.TotalAcquisitionTime = app.rprPars.TotalAcquisitionTime;
            else
                app.parameters.TotalAcquisitionTime = ((app.parameters.tr+app.parameters.tr_extra_us/1000)*app.parameters.NO_VIEWS*app.parameters.NO_AVERAGES*app.parameters.EXPERIMENT_ARRAY)/1000;
            end

            % Orientation
            if isfield(app.parameters,'PHASE_ORIENTATION')
                if app.parameters.PHASE_ORIENTATION
                    app.TextMessage('INFO: phase orientation = 1 / horizontal ... ');
                else
                    app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                end
            end

            % show parameters & set limits
            app.TRViewField.Value = app.tr;
            app.TE0EditField.Value = app.te;
            app.ESPEditField.Value = app.esp;
            app.EchoesViewField.Value = app.ne;
            app.DynamicsViewField.Value = app.nd;
            app.dimxViewField.Value = app.dimx;
            app.dimyViewField.Value = app.dimy;
            app.SlicesViewField.Value = app.ns;

            app.SlicesEditField.Value = 1;
            app.SlicesSlider.Value = 1;
            app.T2MapSlicesEditField1.Value = 1;
            app.T2MapSlicesSlider1.Value = 1;
            app.T2MapSlicesEditField2.Value = 1;
            app.T2MapSlicesSlider2.Value = 1;

            if app.ns > 1
                app.SlicesEditField.Limits = [1, app.ns];
                app.SlicesSlider.Limits = [1, app.ns];
                app.T2MapSlicesEditField1.Limits = [1, app.ns];
                app.T2MapSlicesSlider1.Limits = [1, app.ns];
                app.T2MapSlicesEditField2.Limits = [1, app.ns];
                app.T2MapSlicesSlider2.Limits = [1, app.ns];
            else
                app.SlicesEditField.Limits = [1, 2];
                app.SlicesSlider.Limits = [1, 2];
                app.T2MapSlicesEditField1.Limits = [1, 2];
                app.T2MapSlicesSlider1.Limits = [1, 2];
                app.T2MapSlicesEditField2.Limits = [1, 2];
                app.T2MapSlicesSlider2.Limits = [1, 2];
            end

            app.DynamicsEditField.Value = 1;
            app.T2MapDynamicsEditField1.Value = 1;
            app.T2MapDynamicsEditField2.Value = 1;
            app.DynamicsSlider.Value = 1;
            app.T2MapDynamicsSlider1.Value = 1;
            app.T2MapDynamicsSlider2.Value = 1;

            if app.nd > 1
                app.DynamicsEditField.Limits  = [1, app.nd];
                app.T2MapDynamicsEditField1.Limits  = [1, app.nd];
                app.T2MapDynamicsEditField2.Limits  = [1, app.nd];
                app.DynamicsSlider.Limits  = [1, app.nd];
                app.T2MapDynamicsSlider1.Limits  = [1, app.nd];
                app.T2MapDynamicsSlider2.Limits  = [1, app.nd];
            else
                app.DynamicsEditField.Limits  = [1, 2];
                app.T2MapDynamicsEditField1.Limits  = [1, 2];
                app.T2MapDynamicsEditField2.Limits  = [1, 2];
                app.DynamicsSlider.Limits  = [1, 2];
                app.T2MapDynamicsSlider1.Limits  = [1, 2];
                app.T2MapDynamicsSlider2.Limits  = [1, 2];
            end

            app.EchoesViewField.Value = app.ne;
            app.EchoesEditField.Limits = [1, app.ne];
            app.EchoesSlider.Limits = [1, app.ne];
            app.SLTViewField.Value = app.sl;
            app.FOVViewField.Value = app.fov;
            app.AspectRatioViewField.Value = app.parameters.FOVf/8;
            app.FirstSliceEditField.Value = 1;

            if app.ns > 1
                app.FirstSliceEditField.Limits = [1 app.ns];
                app.LastSliceEditField.Limits = [1 app.ns];
            else
                app.FirstSliceEditField.Limits = [1 2];
                app.LastSliceEditField.Limits = [1 2];
            end

            if app.nd > 1
                app.FirstDynamicEditField.Limits = [1 app.nd];
                app.LastDynamicEditField.Limits = [1 app.nd];
            else
                app.FirstDynamicEditField.Limits = [1 2];
                app.LastDynamicEditField.Limits = [1 2];
            end

            app.LastSliceEditField.Value = app.ns;
            app.LastDynamicEditField.Value = app.nd;
            app.abortFitFlag = false;
            app.abortRegFlag = false;
            
            % File tag
            [~,app.tag,~] = fileparts(app.dataFile);

            % Uncheck/reset some options
            app.CSrecoCheckBox.Value = 0;
            app.DeNoiseCheckBox.Value = 0;
            app.DeNoiseWindowEditField.Value = 8;
            app.DeRingCheckBox.Value = 0;
            app.MobaCheckBox.Value = 0;

            % Window and level
            app.WindowEditField.Value = 32767;
            app.LevelEditField.Value = 16384;
            app.WindowSlider.Value = 32767;
            app.LevelSlider.Value = 16384;
            app.AutoLWButton.Value = 1;

            % Segmentation
            app.structuringElement2D = strel("disk",1);
            app.structuringElement3D = strel("sphere",1);
            app.roiPoints = [];
            
            % reset images
            app.m0map = zeros(app.dimx,app.dimy,app.ns,app.nd);
            app.t2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
            app.r2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
            app.mask = ones(app.dimx,app.dimy,app.ns,app.nd);
            app.images = zeros(app.ne,app.dimx,app.dimy,app.ns,app.nd);
          
            % reset treshold values
            app.threshold = zeros(app.ns,app.nd);
            
        end % SetParameters


        % make TE array
        function MakeTeArray(app)
            
            app.teSelection = [...
                app.CheckBox_1.Value  app.CheckBox_2.Value  app.CheckBox_3.Value  app.CheckBox_4.Value ...
                app.CheckBox_5.Value  app.CheckBox_6.Value  app.CheckBox_7.Value  app.CheckBox_8.Value ...
                app.CheckBox_9.Value  app.CheckBox_10.Value app.CheckBox_11.Value app.CheckBox_12.Value ...
                app.CheckBox_13.Value app.CheckBox_14.Value app.CheckBox_15.Value app.CheckBox_16.Value ...
                app.CheckBox_17.Value app.CheckBox_18.Value app.CheckBox_19.Value app.CheckBox_20.Value ...
                app.CheckBox_21.Value app.CheckBox_22.Value app.CheckBox_23.Value app.CheckBox_24.Value ...
                app.CheckBox_25.Value app.CheckBox_26.Value app.CheckBox_27.Value app.CheckBox_28.Value ...
                app.CheckBox_29.Value app.CheckBox_30.Value app.CheckBox_31.Value app.CheckBox_32.Value ...
                app.CheckBox_33.Value app.CheckBox_34.Value app.CheckBox_35.Value app.CheckBox_36.Value ...
                app.CheckBox_37.Value app.CheckBox_38.Value app.CheckBox_39.Value app.CheckBox_40.Value ...
                app.CheckBox_41.Value app.CheckBox_42.Value app.CheckBox_43.Value app.CheckBox_44.Value ...
                app.CheckBox_45.Value app.CheckBox_46.Value app.CheckBox_47.Value app.CheckBox_48.Value ...
                app.CheckBox_49.Value app.CheckBox_50.Value app.CheckBox_51.Value app.CheckBox_52.Value ...
                app.CheckBox_53.Value app.CheckBox_54.Value app.CheckBox_55.Value app.CheckBox_56.Value ...
                app.CheckBox_57.Value app.CheckBox_58.Value app.CheckBox_59.Value app.CheckBox_60.Value ...
                app.CheckBox_61.Value app.CheckBox_62.Value app.CheckBox_63.Value app.CheckBox_64.Value ...
                ];
            app.teSelection = [app.teSelection ones(1,1024)];
            
        end


        % Set default values for which echoes to include in fitting for spin-echo multi-echo
        function SetSpinEcho(app)
            
                app.CheckBox_1.Value = 0;  app.CheckBox_2.Value = 1;  app.CheckBox_3.Value = 1;  app.CheckBox_4.Value = 1; ...
                app.CheckBox_5.Value = 1;  app.CheckBox_6.Value = 1;  app.CheckBox_7.Value = 1;  app.CheckBox_8.Value = 1; ...
                app.CheckBox_9.Value = 1;  app.CheckBox_10.Value = 1; app.CheckBox_11.Value = 1; app.CheckBox_12.Value = 1; ...
                app.CheckBox_13.Value = 1; app.CheckBox_14.Value = 1; app.CheckBox_15.Value = 1; app.CheckBox_16.Value = 1; ...
                app.CheckBox_17.Value = 1; app.CheckBox_18.Value = 1; app.CheckBox_19.Value = 1; app.CheckBox_20.Value = 1; ...
                app.CheckBox_21.Value = 1; app.CheckBox_22.Value = 1; app.CheckBox_23.Value = 1; app.CheckBox_24.Value = 1; ...
                app.CheckBox_25.Value = 1; app.CheckBox_26.Value = 1; app.CheckBox_27.Value = 1; app.CheckBox_28.Value = 1; ...
                app.CheckBox_29.Value = 1; app.CheckBox_30.Value = 1; app.CheckBox_31.Value = 1; app.CheckBox_32.Value = 1; ...
                app.CheckBox_33.Value = 1; app.CheckBox_34.Value = 1; app.CheckBox_35.Value = 1; app.CheckBox_36.Value = 1; ...
                app.CheckBox_37.Value = 1; app.CheckBox_38.Value = 1; app.CheckBox_39.Value = 1; app.CheckBox_40.Value = 1; ...
                app.CheckBox_41.Value = 1; app.CheckBox_42.Value = 1; app.CheckBox_43.Value = 1; app.CheckBox_44.Value = 1; ...
                app.CheckBox_45.Value = 1; app.CheckBox_46.Value = 1; app.CheckBox_47.Value = 1; app.CheckBox_48.Value = 1; ...
                app.CheckBox_49.Value = 1; app.CheckBox_50.Value = 1; app.CheckBox_51.Value = 1; app.CheckBox_52.Value = 1; ...
                app.CheckBox_53.Value = 1; app.CheckBox_54.Value = 1; app.CheckBox_55.Value = 1; app.CheckBox_56.Value = 1; ...
                app.CheckBox_57.Value = 1; app.CheckBox_58.Value = 1; app.CheckBox_59.Value = 1; app.CheckBox_60.Value = 1; ...
                app.CheckBox_61.Value = 1; app.CheckBox_62.Value = 1; app.CheckBox_63.Value = 1; app.CheckBox_64.Value = 1;
            
                app.T2msLabel.Text = 'T2 (ms)';
                app.T2MapLabel.Text = 'T2 map';
        end


        % Set default values for which echoes to include in fitting for gradient multi echo
        function SetGradientEcho(app)
                
                app.SetSpinEcho;
                app.CheckBox_1.Value = 1;

                app.T2msLabel.Text = 'T2* (ms)';
                app.T2MapLabel.Text = 'T2* map';
            
        end


    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)
            
            try
                app.MessageBox.Value = [message ; app.MessageBox.Value];
                app.MessageBox.Value = app.MessageBox.Value(1:50,1);
                drawnow;
            catch
            end
            
        end % TextMessage
        
        
        % Status icon
        function SetStatus(app, status)
            
            switch status
                
                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.statusLabel.Text = 'OK';
                    app.statusLabel.FontColor = app.green;
                    
                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.statusLabel.Text = 'warning';
                    app.statusLabel.FontColor = app.orange;
                    sound(app.warningSound,48000);
                    
                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.statusLabel.Text = 'error';
                    app.statusLabel.FontColor = app.brightred;
                    sound(app.errorSound,48000);
                case 3
                    app.StatusImage.ImageSource = 'statuswait.gif';
                    app.statusLabel.Text = 'busy';
                    app.statusLabel.FontColor = 'black';
                    
            end
            
            drawnow;
            
        end


    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            
            % disable unnecessary warnings
            clc;
            warning off;
            
            % main window noname
            app.T2mappUIFigure.Name = '';
            
            % Start the parallel pool if it is not running already
            try
                numCores = feature('numcores');
                p = gcp('nocreate');
                if isempty(p)
                    if numCores >= 8
                        parpool(8);
                    else
                        parpool(numCores);
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end
            
            % empty file import and export paths
            app.mrdImportPath = '';
            app.dicomExportPath = '';
            app.gifExportPath = '';
            app.roiExportPath = '';
            app.mrdFlag = true;
            app.data3dFlag = false;
            app.p2roudFlag = false;
                      
            % add subfolders to search path
            if ~isdeployed
                app.oldDir = pwd;
                info = dir(which('T2mapp.mlapp'));
                app.appDir = info.folder;
                cd(app.appDir);
                addpath(genpath(pwd));
                try
                    rmpath(genpath(strcat(pwd,filesep,'T2mapp/')));
                catch
                end
                try
                    rmpath(genpath(strcat(pwd,filesep,'T2mapp_resources/')));
                catch
                end
                try
                    rmpath(genpath(strcat(pwd,filesep,'Standalone installers/')));
                catch
                end
            end
            
            % Initialize user interface
            app.InitializeUI;
            
            % PC or MAC
            try
                if ismac()
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 version');
                    else
                        app.TextMessage('Apple Intel version');
                    end
                else
                    app.TextMessage('PC Intel version');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Elastix
            try
                if ~isdeployed
                    if ismac || isunix
                        [~, result] = system('echo -n $PATH');
                        if ~contains(result,':/usr/local/bin:/usr/local/lib')
                            result = [result ':/usr/local/bin:/usr/local/lib'];
                            setenv('PATH', result);
                        end
                    else
                        [elastixDir, ~] = fileparts(which('elastix.exe'));
                        result = getenv('PATH');
                        if ~contains(result,elastixDir)
                            result = [result ';' elastixDir];
                            setenv('PATH', result);
                        end
                    end
                end
            catch
            end

            % Trick to automatically include the exe and dll file during compilation
            try
                if ispc
                    import('elastix.exe');
                end
            catch
            end
            try
                if ispc
                    import('ANNlib-5.1.dll');
                end
            catch
            end

            % Determine if BART is available by requesting its version number
            app.bartDetectedFlag = false;
            try
                bart(app,'version');
                if ~contains(app.bartVersion,'none')
                    app.bartDetectedFlag = true;
                end
            catch ME
                app.TextMessage(ME.message);
            end

            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected ...');
            else
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end
         
        end

        % Button pushed function: LoadMRDfileButton
        function LoadMRDfileButtonPushed(app, event)
            
            % Load the T2 or T2* data file
            
            % Indicate busy
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.SetStatus(3);
            
            % check standard smis directory first if platform is pc, only 1x per session
            if ispc
                try
                    folder_name = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folder_name = [folder_name,filesep];
                    app.TextMessage('MRD directory found in Windows registry ...');
                    app.TextMessage(strcat('MRD directory =',{' '},folder_name));
                catch 
                    app.TextMessage('Trying standard MRD directory ...');
                    folder_name = 'c:\smis\dev\MRD\';
                end
                if exist(folder_name, 'dir') && app.mrdFlag
                    app.mrdImportPath = folder_name;
                    app.mrdFlag = false;
                end
            end

            try

                % Reset flags
                app.validDataFlag = false;
                app.validRecoFlag = false;
                app.validFitFlag = false;
                app.data3dFlag = false;
                app.p2roudFlag = false;

                % Select a data file
                app.T2mappUIFigure.Visible = 'off';
                [app.mrdFile,app.mrdImportPath] = uigetfile({'*.*'},'Select a data file',app.mrdImportPath);
                app.T2mappUIFigure.Visible = 'on';

                if ~ischar(app.mrdFile)
                    ME = MException('T2mapp:UserCancel','User cancel ...');
                    throw(ME);
                end

                if contains(app.mrdFile,"MRD")

                    % MR Solutions data
                    app.TextMessage('Loading MRD k-space data ...');
                    validfile = importMRD(app);

                    if ~validfile
                        ME = MException('T2mapp:InvalidFile','Not a valid MRD file ...');
                        throw(ME);
                    end

                    switch app.dataType
                        case "grad-echo"
                            app.SetGradientEcho;
                        case "spin-echo"
                            app.SetSpinEcho;
                    end

                elseif contains(app.mrdFile,"fid")

                    % B-type scanner data
                    app.TextMessage('Loading b-type k-space data ...');
                    app.data = {};
                    [app.data, app.images, app.parameters] = importB(app.mrdImportPath);
                    app.DatafileEditField.Value = 'fid';
                    app.dataFile = 'fid';
                    if contains(app.parameters.PPL,'flash')
                        app.dataType = "grad-echo";
                        app.SetGradientEcho;
                        app.nrCoils = 1;
                    end

                else

                    ME = MException('T2mapp:InvalidFile','Not a valid data file ...');
                    throw(ME);

                end

                SetParameters(app);

                % Reset buttons
                app.validDataFlag = true;
                app.SetFitButtons(1);
                app.SetLights([1,0,0,0,0,0,0]);
                app.SetButtons([1,1,0,0,0,0,0,1]);
                app.SetStatus(0);

                % Do a fast FFT reconstruction
                app.CSrecoCheckBox.Value = 0;
                app.RecoButtonPushed;

                % Show the images
                PlotImageFig(app);

            catch ME

                % Something went wrong during loading of the data
                app.TextMessage(ME.message);
                app.mrdImportPath = '';
                app.validDataFlag = false;
                app.SetFitButtons(0);
                app.SetLights([0,0,0,0,0,0,0]);
                app.SetButtons([1,0,0,0,0,0,0,1]);
                app.SetStatus(2);

            end

        end

        % Value changed function: ESPEditField, TE0EditField
        function TEViewFieldValueChanged(app, event)
            
            app.te = app.TE0EditField.Value;
            app.parameters.te = app.TE0EditField.Value;
            app.esp = app.ESPEditField.Value;
            app.tes = app.te + app.esp*linspace(0,app.ne-1,app.ne)'; % echo times
            app.TextMessage(strcat("Echo times = ",num2str(app.tes')));
            
        end

        % Button pushed function: RecoButton
        function RecoButtonPushed(app, event)
            
            if app.validDataFlag
            
                % Indicate busy
                app.SetLights([1,0,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TabGroup2.SelectedTab = app.RawimagesTab;
            
                % reset some parameters
                app.SlicesEditField.Value = 1;
                app.SlicesSlider.Value = 1;
                app.EchoesEditField.Value = 1;
                app.EchoesSlider.Value = 1;
                app.ThresholdEditField.Value = 1;
                app.ThresholdSlider.Value = 1;
                app.T2MapSlicesEditField1.Value = 1;
                app.T2MapSlicesSlider1.Value = 1;
                app.T2MapSlicesEditField2.Value = 1;
                app.T2MapSlicesSlider2.Value = 1;
                app.DynamicsEditField.Value = 1;
                app.T2MapDynamicsEditField1.Value = 1;
                app.T2MapDynamicsEditField2.Value = 1;
                app.DynamicsSlider.Value = 1;
                app.T2MapDynamicsSlider1.Value = 1;
                app.T2MapDynamicsSlider2.Value = 1;

                % reset m0 and t2map
                app.m0map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                app.t2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                app.r2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                app.mask = ones(app.dimx,app.dimy,app.ns,app.nd);

                try

                    if app.bartDetectedFlag && (app.CSrecoCheckBox.Value == 1)

                        % Perform the BART compressed sensing reconstruction
                        app.TextMessage('CS reconstruction ...');

                        app.images = CSreco(app);

                    else

                        % Perform the FFT reconstruction
                        app.TextMessage('FFT reconstruction ...');

                        app.images = fftReco(app);

                    end

                    % Readout oversampling
                    if isfield(app.parameters,"oversample")
                        if app.parameters.oversample == 1
                            app.TextMessage('Readout oversampling ...');
                            os = round(size(app.images,2)/4);
                            app.images = app.images(:,os+1:end-os,:,:,:);
                        end
                    end

                    % Denoising
                    if app.DeNoiseCheckBox.Value
                        app.TextMessage('PCA denoising ...');
                        app.images = PCAdenoise(app.images,app.DeNoiseWindowEditField.Value);
                    end

                    % Gibbs ringing suppression
                    if app.DeRingCheckBox.Value
                        app.TextMessage('Gibbs ringing suppression ...');
                        app.images = unRing(app.images);
                    end

                    % Normalize to convenient range
                    app.images = app.images*32767/max(app.images(:));

                    % Success
                    app.TextMessage('Ready ...');
                    app.validRecoFlag = true;
                    app.validRegFlag = false;
                    app.validFitFlag = false;
                    
                    % Show the images
                    PlotImageFig(app);
                    PlotMaps(app);

                    % Reset buttons
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.validRecoFlag = false;
                    app.validFitFlag = false;
                    app.validRegFlag = false;

                    % Reset buttons
                    app.SetLights([1,0,0,0,0,0,0]);
                    app.SetButtons([1,1,0,0,0,0,0,1]);
                    app.SetImageSelectionButtons(0);
                    app.SetFitButtons(0);
                    app.SetStatus(2);


                end

            end
    
        end

        % Value changed function: DynamicsEditField, EchoesEditField, 
        % ...and 1 other component
        function ImageSelectionEditFieldValueChanged(app, event)
            
            % Adjust the slider
            app.SlicesSlider.Value = app.SlicesEditField.Value;
            app.EchoesSlider.Value = app.EchoesEditField.Value;
            app.DynamicsSlider.Value = app.DynamicsEditField.Value;
        
            % Plot the raw image 
            PlotImageFig(app);
            
        end

        % Value changed function: DynamicsSlider, EchoesSlider, 
        % ...and 1 other component
        function ImageSelectionSliderValueChanged(app, event)
            
            % Adjust the slices edit field value
            app.SlicesEditField.Value = round(app.SlicesSlider.Value);
            app.EchoesEditField.Value = round(app.EchoesSlider.Value);
            app.DynamicsEditField.Value = round(app.DynamicsSlider.Value);
              
            % Plot the raw image 
            PlotImageFig(app);
            
        end

        % Button pushed function: RegistrationButton
        function RegistrationButtonPushed(app, event)
    
            if app.validRecoFlag

                app.validRegFlag = false;
                app.validFitFlag = false;

                try

                    % Indicate busy
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([0,0,0,0,0,0,0,0]);
                    app.SetFitButtons(0);
                    app.SetImageSelectionButtons(0);
                    app.SetSegmentationButtons(0);
                    app.SetMapButtons(0);
                    app.SetStatus(3);
                    app.abortRegFlag = false;
                    app.AbortRegButton.Enable = 'on';

                    % Register all slices to 1st echo time image
                    registerImages(app);
                    app.validRegFlag = true;

                    % reset m0, t2map, threshold
                    app.m0map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                    app.t2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                    app.r2map = zeros(app.dimx,app.dimy,app.ns,app.nd);
                    app.mask = ones(app.dimx,app.dimy,app.ns,app.nd);
                    app.threshold = zeros(app.ns,app.nd);

                    % replot the images
                    app.PlotImageFig;
                    app.PlotMaps;

                    % Reset buttons
                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.SetLights([1,1,1,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during registration ...');

                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(0);
                    app.AbortRegButton.Enable = 'off';
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetStatus(2);

                end

            end
            
        end

        % Button pushed function: AbortRegButton
        function AbortRegButtonPushed(app, event)
            
            app.abortRegFlag = true;
            drawnow;

        end

        % Button pushed function: ThresholdButton
        function ThresholdButtonPushed(app, event)
            
            if app.validRecoFlag
                
                % Indicate busy
                app.SetLights([1,1,2,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ....');

                try

                    % Determine automatic threshold using the Otsu method for each slice
                    for dynamic = 1:app.nd
                        for slice = 1:app.ns
                            maxim = squeeze(app.images(1,:,:,slice,dynamic));
                            app.threshold(slice,dynamic) = graythresh(mat2gray(app.images(1,:,:,slice,dynamic))) * max(maxim(:));
                        end
                    end

                    % Set the automatic mask for all slices
                    app.mask = ones(app.dimx,app.dimy,app.ns);
                    for dynamic = 1:app.nd
                        for slice = 1:app.ns
                            app.mask(:,:,slice,dynamic) = apply_threshold_t2(squeeze(app.images(1,:,:,slice,dynamic)),app.threshold(slice,dynamic));
                        end
                    end

                    % Display the threshold value for the current slice
                    app.ThresholdEditField.Value = app.threshold(app.SlicesEditField.Value,app.DynamicsEditField.Value);

                    % Display the mask in the image
                    PlotImageFig(app);

                    % Indicate busy
                    app.SetLights([1,1,2,1,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(0);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.RecoButtonPushed;

                end
                
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            % Get the current slice
            cslice = app.SlicesEditField.Value;
            
            % Change the value of the threshold for this slice and update the slider
            app.threshold(cslice) = app.ThresholdEditField.Value;
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;
            
            % Apply the new threshold
            app.mask(:,:,cslice) = apply_threshold_t2(squeeze(app.images(1,:,:,cslice)),app.threshold(cslice));
            
            % Display the result;
            PlotImageFig(app)
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            % Threshold slider changed
            % Adjust Threshold and call threshold change button
            app.ThresholdEditField.Value = app.ThresholdSlider.Value;
            ThresholdEditFieldValueChanged(app, event);
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            
            % Morphological erosion on current slice
            if app.data3dFlag
                app.mask(:,:,:,app.DynamicsEditField.Value) = 1-imerode(1-squeeze(app.mask(:,:,:,app.DynamicsEditField.Value)),app.structuringElement3D);
            else
                app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value) = 1-imerode(1-squeeze(app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value)),app.structuringElement2D);
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
        
            % Morphological dilation on current slice
            if app.data3dFlag
                app.mask(:,:,:,app.DynamicsEditField.Value) = 1-imdilate(1-squeeze(app.mask(:,:,:,app.DynamicsEditField.Value)),app.structuringElement3D);
            else
                app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value) = 1-imdilate(1-squeeze(app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value)),app.structuringElement2D);
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            % Morphological closing on current slice
            if app.data3dFlag
                app.mask(:,:,:,app.DynamicsEditField.Value) = 1-imdilate(1-squeeze(app.mask(:,:,:,app.DynamicsEditField.Value)),app.structuringElement3D);
                app.mask(:,:,:,app.DynamicsEditField.Value) = 1-imerode(1-squeeze(app.mask(:,:,:,app.DynamicsEditField.Value)),app.structuringElement3D);
            else
                app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value) = 1-imdilate(1-squeeze(app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value)),app.structuringElement2D);
                app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value) = 1-imerode(1-squeeze(app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value)),app.structuringElement2D);
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % Morphological open on current slice
             if app.data3dFlag
                app.mask(:,:,:,app.DynamicsEditField.Value) = 1-imopen(1-squeeze(app.mask(:,:,:,app.DynamicsEditField.Value)),app.structuringElement3D);
            else
                app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value) = 1-imopen(1-squeeze(app.mask(:,:,app.SlicesEditField.Value,app.DynamicsEditField.Value)),app.structuringElement2D);
            end

            % Display the result;
            PlotImageFig(app);
            
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)
             
            app.WindowSlider.Value = app.WindowEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            app.WindowEditField.Value = round(app.WindowSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            
            app.LevelSlider.Value = app.LevelEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)
            
            app.LevelEditField.Value = round(app.LevelSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;
            
        end

        % Value changed function: AutoLWButton
        function AutoLWButtonValueChanged(app, event)
            
            if app.AutoLWButton.Value == 1
                app.PlotImageFig;
            end
            
        end

        % Button pushed function: T2FitButton
        function T2FitButtonPushed(app, event)
            
            if app.validRecoFlag

                app.SetLights([2,2,2,2,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(1);
                app.SetStatus(3);
                app.TextMessage('Fitting T2 maps ....');
                app.FitProgressGauge.Value = 0;
                app.validFitFlag = false;
                app.abortFitFlag = false;
                app.AbortFitButton.Enable = 'on';
                drawnow;

                try

                    % TE selection
                    app.MakeTeArray;
                    nr_tes = length(find(app.teSelection(1:app.ne) == 1));

                    % Some checks
                    if (app.te <= 0) || (app.esp <= 0)
                        ME = MException('T2mapp:TEerror','ERROR: TE0 and ESP should be > 0 for fitting ...');
                        throw(ME);
                    end

                    if nr_tes == 0
                        ME = MException('T2mapp:EchoError','ERROR: Number of echoes should be greater than 1 ...');
                        throw(ME);
                    end

                    if app.bartDetectedFlag && app.MobaCheckBox.Value

                        % -------------------------------------------------------
                        %     Model-based fitting
                        % -------------------------------------------------------

                        app.TextMessage("Model based fitting ...");

                        if app.data3dFlag

                            % --------
                            % -- 3D --
                            % --------

                            % Progress counter reset
                            cnt = 1;
                            totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1);

                            % Timing parameters
                            app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                            elapsedTime = 0;

                            % For selected dynamics
                            dynamic = app.FirstDynamicEditField.Value - 1;

                            while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                                dynamic = dynamic + 1;

                                tic;

                                app.TextMessage(sprintf("Fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                                drawnow;

                                % Do the T2 or T2* fitting
                                switch app.dataType

                                    case "spin-echo"

                                        [app.m0map(:,:,:,dynamic),app.t2map(:,:,:,dynamic)] = dothemobaT2fit3D(app,dynamic);
                                        app.r2map(:,:,:,dynamic) = zeros(size(squeeze(app.r2map(:,:,:,dynamic))));

                                    case "grad-echo"

                                        [app.m0map(:,:,:,dynamic),app.t2map(:,:,:,dynamic)] = dothemobaT2starfit3D(app,dynamic);
                                        app.r2map(:,:,:,dynamic) = zeros(size(squeeze(app.r2map(:,:,:,dynamic))));

                                end

                                % Plot T2 map thus far with auto scale
                                try
                                    scale = round(2.5*mean(nonzeros(abs(app.t2map(:).*app.mask(:)))));
                                    scale(scale<5)=5;
                                catch
                                    scale = 100;
                                    
                                end
                                app.T2ScaleEditField.Value = scale;

                                app.validFitFlag = true;
                                PlotMaps(app);
                                app.validFitFlag = false;

                                % Update progresss bar
                                app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps);
                                drawnow;

                                % Update the timing indicator
                                elapsedTime = elapsedTime + toc;
                                estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                                timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                                timeRemaining(timeRemaining<0) = 0;
                                app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                                drawnow;

                                cnt = cnt + 1;

                            end % Loop over dynamics

                        else

                            % --------
                            % -- 2D --
                            % --------

                            % Progress counter reset
                            cnt = 1;
                            totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1)*...
                                (app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1);

                            % Timing parameters
                            app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                            elapsedTime = 0;

                            % For selected dynamics
                            dynamic = app.FirstDynamicEditField.Value - 1;

                            while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                                dynamic = dynamic + 1;

                                % for selected slices
                                slice = app.FirstSliceEditField.Value - 1;

                                while slice < app.LastSliceEditField.Value && ~app.abortFitFlag

                                    slice = slice + 1;

                                    tic;

                                    app.TextMessage(sprintf("Fitting slice %d of %d ...",slice-app.FirstSliceEditField.Value+1,app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1));
                                    app.TextMessage(sprintf("Fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                                    drawnow;

                                    % Do the T2 or T2* fitting
                                    switch app.dataType

                                        case "spin-echo"

                                            [app.m0map(:,:,slice,dynamic),app.t2map(:,:,slice,dynamic)] = dothemobaT2fit2D(app,slice,dynamic);
                                            app.r2map(:,:,slice,dynamic) = zeros(size(squeeze(app.r2map(:,:,slice,dynamic))));

                                        case "grad-echo"

                                            [app.m0map(:,:,slice,dynamic),app.t2map(:,:,slice,dynamic)] = dothemobaT2starfit2D(app,slice,dynamic);
                                            app.r2map(:,:,slice,dynamic) = zeros(size(squeeze(app.r2map(:,:,slice,dynamic))));

                                    end

                                    % Plot T2 map thus far with auto scale
                                    try
                                        scale = round(2.5*mean(nonzeros(abs(app.t2map(:).*app.mask(:)))));
                                        scale(scale<5)=5;
                                        app.T2ScaleEditField.Value = scale;
                                    catch
                                        scale = 100;
                                        app.T2ScaleEditField.Value = scale;
                                    end

                                    app.validFitFlag = true;
                                    PlotMaps(app);
                                    app.validFitFlag = false;
                                  
                                    % Update progresss bar
                                    app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps );
                                 
                                    % Update the timing indicator
                                    elapsedTime = elapsedTime + toc;
                                    estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                                    timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                                    timeRemaining(timeRemaining<0) = 0;
                                    app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                                    drawnow;

                                    cnt = cnt + 1;
                                   
                                end % Loop over slices

                            end % Loop over dynamics

                        end % 3D or 2D

                    else

                        % ---------------------------------------
                        %     Normal pixel-wise fitting
                        % ---------------------------------------

                        % Progress counter reset
                        cnt = 1;
                        totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1)*...
                            (app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1);

                        % Timing parameters
                        app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                        elapsedTime = 0;

                        % For selected dynamics
                        dynamic = app.FirstDynamicEditField.Value - 1;

                        while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                            dynamic = dynamic + 1;

                            % for selected slices
                            slice = app.FirstSliceEditField.Value - 1;

                            while slice < app.LastSliceEditField.Value && ~app.abortFitFlag

                                slice = slice + 1;

                                tic;

                                app.TextMessage(sprintf("Fitting slice %d of %d ...",slice-app.FirstSliceEditField.Value+1,app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1));
                                app.TextMessage(sprintf("Fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                                drawnow;

                                % Do the fit for 1 slice
                                [app.m0map(:,:,slice,dynamic),app.t2map(:,:,slice,dynamic),app.r2map(:,:,slice,dynamic)] = dotheT2fit2D(squeeze(app.images(:,:,:,slice,dynamic)), squeeze(app.mask(:,:,slice,dynamic)), app.tes, app.Rsquare.Value, app.teSelection);

                                % Plot T2 map thus far with auto scale
                                try
                                    scale = round(2.5*mean(nonzeros(abs(app.t2map(:).*app.mask(:)))));
                                    scale(scale<5)=5;
                                    app.T2ScaleEditField.Value = scale;
                                catch
                                    scale = 100;
                                    app.T2ScaleEditField.Value = scale;
                                end

                                app.validFitFlag = true;
                                PlotMaps(app);
                                app.validFitFlag = false;

                                % Update progresss bar
                                app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps);
                              
                                % Update the timing indicator
                                elapsedTime = elapsedTime + toc;
                                estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                                timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                                timeRemaining(timeRemaining<0) = 0;
                                app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                                drawnow;

                                cnt = cnt + 1;
                             
                            end % Loop over slices

                        end % Loop over dynamics

                    end % MOBA true / false

                    app.TextMessage('Finished ... ');
                    app.EstimatedFitTimeViewField.Value = 'Finished ...';

                    % Reset buttons
                    app.SetLights([2,2,2,2,1,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetStatus(0);
                    app.validFitFlag = true;
                    app.AbortFitButton.Enable = 'off';

                catch ME

                    app.TextMessage(ME.message)
                    app.TextMessage("ERROR: Something went wrong during fitting ...");
                
                    % Reset buttons
                    app.SetLights([2,2,2,2,0,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetStatus(2);
                    app.validFitFlag = false;
                    app.AbortFitButton.Enable = 'off';

                end

            end
         
        end

        % Button pushed function: AbortFitButton
        function AbortFitButtonPushed(app, event)
            
            app.abortFitFlag = true; 
            drawnow;
        
        end

        % Value changed function: FirstSliceEditField, LastSliceEditField
        function SliceRangeEditFieldValueChanged(app, event)
            
            app.FirstSliceEditField.Value(app.FirstSliceEditField.Value > app.ns) = app.ns;
            app.LastSliceEditField.Value(app.LastSliceEditField.Value > app.ns) = app.ns;

            if app.FirstSliceEditField.Value > app.LastSliceEditField.Value
                app.LastSliceEditField.Value = app.FirstSliceEditField.Value;
            end
            
        end

        % Value changed function: FirstDynamicEditField, 
        % ...and 1 other component
        function DynamicRangeEditFieldValueChanged(app, event)
                        
            app.LastDynamicEditField.Value(app.LastDynamicEditField.Value > app.nd) = app.nd;
            app.FirstDynamicEditField.Value(app.FirstDynamicEditField.Value > app.nd) = app.nd;
            
            if app.FirstDynamicEditField.Value > app.LastDynamicEditField.Value
                app.LastDynamicEditField.Value = app.FirstDynamicEditField.Value;
            end
            
        end

        % Value changed function: T2MapDynamicsEditField1, 
        % ...and 1 other component
        function T2MapSelectionEditField1ValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.T2MapSlicesSlider1.Value = round(app.T2MapSlicesEditField1.Value);
            app.T2MapDynamicsSlider1.Value = round(app.T2MapDynamicsEditField1.Value);

            app.T2MapSlicesEditField2.Value = app.T2MapSlicesEditField1.Value;
            app.T2MapSlicesSlider2.Value = app.T2MapSlicesSlider1.Value;

            app.T2MapDynamicsEditField2.Value = app.T2MapDynamicsEditField1.Value;
            app.T2MapDynamicsSlider2.Value = app.T2MapDynamicsSlider1.Value; 
            
            % Plot the T2-map for the new slice and dynamic
            PlotMaps(app);
            
        end

        % Value changed function: T2MapDynamicsEditField2, 
        % ...and 1 other component
        function T2MapSelectionEditField2ValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.T2MapSlicesSlider2.Value = round(app.T2MapSlicesEditField2.Value);

            app.T2MapSlicesEditField1.Value = app.T2MapSlicesEditField2.Value;
            app.T2MapSlicesSlider1.Value = app.T2MapSlicesSlider2.Value;

            app.T2MapDynamicsEditField1.Value = app.T2MapDynamicsEditField2.Value;
            app.T2MapDynamicsSlider1.Value = app.T2MapDynamicsSlider2.Value; 
            
            % Plot the T2-map for the new slice and dynamic
            PlotMaps(app);
            
        end

        % Value changed function: T2MapDynamicsSlider1, T2MapSlicesSlider1
        function T2MapSelectionSlider1ValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.T2MapSlicesEditField1.Value = round(app.T2MapSlicesSlider1.Value);
            app.T2MapSlicesEditField2.Value = round(app.T2MapSlicesSlider1.Value);
            app.T2MapSlicesSlider2.Value = app.T2MapSlicesSlider1.Value;

            % Change the Dynamic nr value according to the slider
            app.T2MapDynamicsEditField1.Value = round(app.T2MapDynamicsSlider1.Value);
            app.T2MapDynamicsEditField2.Value = round(app.T2MapDynamicsSlider1.Value);
            app.T2MapDynamicsSlider2.Value = app.T2MapDynamicsSlider1.Value;      
            
            % Plot the T2-map for the new slice and dynamic
            PlotMaps(app);
            
        end

        % Value changed function: T2MapDynamicsSlider2, T2MapSlicesSlider2
        function T2MapSelectionSlider2ValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.T2MapSlicesEditField2.Value = round(app.T2MapSlicesSlider2.Value);
            app.T2MapSlicesEditField1.Value = round(app.T2MapSlicesSlider2.Value);
            app.T2MapSlicesSlider1.Value = app.T2MapSlicesSlider2.Value;
      
            % Change the Dynamic nr value according to the slider
            app.T2MapDynamicsEditField2.Value = round(app.T2MapDynamicsSlider2.Value);
            app.T2MapDynamicsEditField1.Value = round(app.T2MapDynamicsSlider2.Value);
            app.T2MapDynamicsSlider1.Value = app.T2MapDynamicsSlider2.Value;     

            % Plot the T2-map for the new slice and dynamic
            PlotMaps(app);
            
        end

        % Value changed function: T2ScaleEditField
        function T2ScaleEditFieldValueChanged(app, event)
            
            % Plot the T2 map with the new scale
            PlotMaps(app);
            
        end

        % Button pushed function: T2ScaleAutoButton
        function T2ScaleAutoButtonPushed(app, event)
            
            % Estimate a suitable scale for the T2 map
            % Auto-scale = 2 x mean of the T2 values in the image
            scale = round(2*mean(nonzeros(squeeze(app.t2map(app.T2MapSlicesEditField1.Value,:))))); 
            scale(isnan(scale))=100;
            scale(scale<5)=5;
            app.T2ScaleEditField.Value = scale;
            
            % Plot the map
            PlotMaps(app);
            
        end

        % Window scroll wheel function: T2mappUIFigure
        function T2MappUIFigureWindowScrollWheel(app, event)
            
            if app.ns > 1

                verticalScrollCount = event.VerticalScrollCount;

                % Get the Position of the GUI and the position of the mouse
                set(0,'units','pixels');
                guiposition = get(app.T2mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');
                Xpos = cursorposition(1)-guiposition(1);

                if Xpos < 820

                    if app.validRecoFlag

                        if verticalScrollCount > 0
                            newslice = app.SlicesEditField.Value + 1;
                            newslice(newslice>app.SlicesEditField.Limits(2)) = app.SlicesEditField.Limits(2);
                        else
                            newslice = app.SlicesEditField.Value - 1;
                            newslice(newslice<1) = 1;
                        end

                        app.SlicesEditField.Value = newslice;
                        app.ImageSelectionEditFieldValueChanged;

                    end

                else

                    if ~app.roiFlag

                        if app.validFitFlag

                            if verticalScrollCount > 0
                                newslice = app.T2MapSlicesEditField1.Value + 1;
                                newslice(newslice>app.T2MapSlicesEditField1.Limits(2)) = app.T2MapSlicesEditField1.Limits(2);
                            else
                                newslice = app.T2MapSlicesEditField1.Value - 1;
                                newslice(newslice<1) = 1;
                            end

                            app.T2MapSlicesEditField1.Value = newslice;
                            app.T2MapSlicesEditField2.Value = newslice;
                            app.T2MapSelectionEditField1ValueChanged;
                            app.T2MapSelectionEditField2ValueChanged;

                        end

                    end

                end

                drawnow;

            end
                        
        end

        % Selection changed function: ColorMapButtonGroup
        function ColorMapButtonGroupSelectionChanged2(app, event)
            
            if app.grayButton.Value == 1 
                app.t2cmap = gray; 
            end
            
            if app.autumnButton.Value == 1
                app.t2cmap = autumn; 
            end
            
            if app.winterButton.Value == 1
                app.t2cmap = winter; 
            end
            
            if app.parulaButton.Value == 1
                app.t2cmap = parula; 
            end
            
            if app.hotButton.Value == 1
                app.t2cmap = hot; 
            end
            
            % Background should always be black
            app.t2cmap(1,:) = [0 0 0];
            
            % Plot the T2 map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup2
        function ColorMapButtonGroup2SelectionChanged(app, event)
            
            if app.grayButton2.Value == 1 
                app.m0cmap = gray; 
            end
            
            if app.autumnButton2.Value == 1
                app.m0cmap = autumn; 
            end
            
            if app.winterButton2.Value == 1
                app.m0cmap = winter; 
            end
            
            if app.parulaButton2.Value == 1
                app.m0cmap = parula; 
            end
            
            if app.hotButton2.Value == 1
                app.m0cmap = hot; 
            end
            
            % Background should always be black
            app.m0cmap(1,:) = [0 0 0];
            
            % Plot the M0 map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup3
        function ColorMapButtonGroup3SelectionChanged(app, event)
              
            if app.grayButton3.Value == 1 
                app.r2cmap = gray; 
            end
            
            if app.autumnButton3.Value == 1
                app.r2cmap = autumn; 
            end
            
            if app.winterButton3.Value == 1
                app.r2cmap = winter; 
            end
            
            if app.parulaButton3.Value == 1
                app.r2cmap = parula; 
            end
            
            if app.hotButton3.Value == 1
                app.r2cmap = hot; 
            end
            
            % Background should always be black
            app.r2cmap(1,:) = [0 0 0];
            
            % Plot the R2 map with the new colormap
            PlotMaps(app);
            
            
        end

        % Image clicked function: M0Image, T2Image
        function T2ImageClicked(app, event)

            if app.validFitFlag

                % Get the Position of the GUI and the position of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.T2mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');

                % Position of the cursor click in the image window
                imagepos = [862,412]; % position of bottom-left corner of image in the app (862,412)
                Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                % Image aspect ratio
                if app.parameters.PHASE_ORIENTATION
                    aratio = app.AspectRatioViewField.Value;
                    xsize = app.dimy;
                    ysize = app.dimx;
                else
                    aratio = 1/(app.AspectRatioViewField.Value);
                    xsize = app.dimx;
                    ysize = app.dimy;
                end

                % Correct for non-square image
                imsize = 313;
                if aratio >= 1
                    xcor = (Xpos/imsize) * xsize;
                    ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                else
                    xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                    ycor = (Ypos/imsize) * ysize;
                end

                % Round to nearest integer
                xcor = round(xcor);
                ycor = round(ycor);

                % Current slice
                zcor = app.T2MapSlicesEditField1.Value;

                % Keep the values within matrix boundaries
                xcor(xcor<1) = 1;
                ycor(ycor<1) = 1;
                xcor(xcor>xsize) = xsize;
                ycor(ycor>ysize) = ysize;
                app.XEditField.Value = xcor;
                app.YEditField.Value = ycor;

                % ROI or cross-hair display
                if app.roiFlag

                    % Include points within Radius
                    se = strel("disk",app.BrushEditField.Value-1);
                    [nzrow,nzcol] = find(se.Neighborhood==1);
                    nzc = [nzrow,nzcol] - [app.BrushEditField.Value-1,app.BrushEditField.Value-1];

                    % Paint ROI
                    for i = 1:size(nzc,1)
                        if (xcor+nzc(i,1)>0) && (ycor+nzc(i,2)>0) && (xcor+nzc(i,1)<=xsize) && (ycor+nzc(i,2)<=ysize)
                            app.roiPoints = [app.roiPoints; [xcor+nzc(i,1) ycor+nzc(i,2) zcor]];
                        end
                    end
                    app.roiPoints = unique(app.roiPoints,'rows');
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==zcor, :);
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);

                else

                    % Plot a cross
                    app.roiPoints = [xcor ycor zcor];
                    app.PlotCross(app.roiPoints(1),app.roiPoints(2),ysize);

                end

                % Show the resulting T2 values and fits
                app.CalculateRoiT2;

            end
               
        end

        % Button pushed function: ROIbutton, ROIbutton2
        function ROIbuttonButtonPushed(app, event)
             
            % Toggle between ROI drawing and cross-hair drawing
             
             if strcmp(app.ROIbutton.Text,'ROI')
                 
                 app.ROIbutton.Text = 'Cross';
                 app.ROIbutton2.Text = 'Cross';
                 app.TextMessage('ROI drawing selected ...');
                 app.EraseButton.Visible = 'on';
                 app.EraseButton2.Visible = 'on';
                 app.T2ViewField2.Visible = 'on';
                 app.pixelValueLabel.Text = 'Pixel averaged';
                 app.ROIaveragedLabel.Visible = 'on';
                 app.BrushEditField.Visible = 'on';
                 app.brushEditFieldLabel.Visible = 'on';
                 app.BrushEditField2.Visible = 'on';
                 app.brushEditField_2Label.Visible = 'on';
                 app.roiFlag = true;

             else

                 app.ROIbutton.Text = 'ROI';
                 app.ROIbutton2.Text = 'ROI';
                 app.TextMessage('Cross-hair selected ...');
                 app.EraseButton.Visible = 'off';
                 app.EraseButton2.Visible = 'off';
                 app.T2ViewField2.Visible = 'off';
                 app.pixelValueLabel.Text = 'Pixel value';
                 app.ROIaveragedLabel.Visible = 'off';
                 app.BrushEditField.Visible = 'off';
                 app.brushEditFieldLabel.Visible = 'off';
                 app.BrushEditField2.Visible = 'off';
                 app.brushEditField_2Label.Visible = 'off';
                 app.roiFlag = false;
                 
             end
            
             app.roiPoints = [];
             delete(app.roiT2);
             delete(app.roiM0);
             delete(app.crossT2);
             delete(app.crossM0);
             app.PlotMaps;
             
        end

        % Value changed function: BrushEditField
        function BrushEditFieldValueChanged(app, event)
            
            app.BrushEditField2.Value = app.BrushEditField.Value;
            
        end

        % Value changed function: BrushEditField2
        function BrushEditField2ValueChanged(app, event)
        
            app.BrushEditField.Value = app.BrushEditField2.Value;
            
        end

        % Button pushed function: EraseButton, EraseButton2
        function EraseButtonPushed(app, event)
            
            app.roiPoints = [];
            delete(app.roiT2);
            delete(app.roiM0);
            app.PlotMaps;
            
        end

        % Button pushed function: ExportRoiDataButton
        function ExportRoiDataButtonPushed(app, event)

            try

                if app.validFitFlag && ~isempty(app.roiPoints)

                    app.T2mappUIFigure.Visible = 'off';
                    app.roiExportPath = uigetdir(app.roiExportPath,'Raw data export path ...');
                    app.T2mappUIFigure.Visible = 'on';

                    if ~ischar(app.roiExportPath)
                        app.roiExportPath = '';
                        ME = MException('T2mapp:userCancel','User cancel ...');
                        throw(ME);
                    end

                    rdata = [app.tes app.roiIntData'];
                    writematrix(rdata,strcat(app.roiExportPath,filesep,'roiData.csv'));
                    app.TextMessage('Exporting image intensities from ROI ...');

                end

                app.SetStatus(0);

            catch ME

                app.TextMessage(ME.message);
                app.SetStatus(2);

            end

        end

        % Button pushed function: ExportDicomButton
        function ExportDicomButtonPushed(app, event)
            
            if app.validFitFlag

                % Indicate busy
                app.SetLights([2,2,2,2,2,0,2]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                try

                    % Search for dicom file
                    app.TextMessage('DICOM export ...');

                    % Assuming that MRD and Data directory share the same base-directory
                    % Example: base / MRD / 343 / 8455 /

                    % Base / data / 343 / DICOM / 8455 /
                    dcmSpath{1} = replace(app.mrdImportPath,"MRD","Data");
                    try
                        k = strfind(dcmSpath{1},"Data");
                        fs = strfind(dcmSpath{1}(k:end),filesep);
                        dcmSpath{1}= dcmSpath{1}(1:k+fs(3)-1);
                        dcmSpath{1} = insertAfter(dcmSpath{1},k+fs(2)-1,strcat("DICOM",filesep));
                        dcmIndx{1} = 2;
                    catch
                        dcmSpath{1} = app.mrdImportPath;
                    end

                    % Base / data / 343 / DICOM / 8455 / 1 /
                    dcmSpath{2} = strcat(dcmSpath{1},'1',filesep);
                    dcmIndx{2} = 3;

                    % Base / data / 343 / DICOM / 8455 / 2 /
                    dcmSpath{3} = strcat(dcmSpath{1},'2',filesep);
                    dcmIndx{3} = 3;

                    % Base / data / 343 / DICOM / 8455 / 3 /
                    dcmSpath{4} = strcat(dcmSpath{1},'3',filesep);
                    dcmIndx{4} = 3;

                    % Check if one of the directories contains dicom files
                    dcmExist = false;
                    for i=1:length(dcmSpath)
                        if ~isempty(dir(strcat(dcmSpath{i},"*.dcm")))
                            dcmPath = dcmSpath{i};
                            app.TextMessage(strcat("Found DICOM information in ",dcmPath));
                            filesepIdx = strfind(dcmPath, filesep);
                            app.dicomExportPath = dcmPath(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    if dcmExist

                        % Dicom export using Dicom information
                        app.TextMessage(strcat("DICOM export directory = ",app.dicomExportPath));
                        export_dicom_t2_dcm(app.dicomExportPath,dcmPath,app.m0map,app.t2map,app.r2map,app.parameters);
                        app.TextMessage('Export with DICOM information ...');

                    else

                        % Ask for dicom export directory
                        app.T2mappUIFigure.Visible = 'off';
                        app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                        app.T2mappUIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.dicomExportPath)
                            ME = MException('T1mapp:userCancel','User cancel ...');
                            throw(ME);
                        end

                        % Dicom export without dicom information
                        dicomExportDir = strcat(app.dicomExportPath,filesep,"DICOM",filesep);
                        app.TextMessage(strcat("DICOM export directory = ",dicomExportDir));
                        export_dicom_t2(dicomExportDir,app.m0map,app.t2map,app.r2map,app.parameters,app.tag);
                        app.TextMessage('WARNING: Export without DICOM information ...');

                    end

                    % Success
                    app.TextMessage("DICOM export ready ...");
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,1,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during Dicom export ...');
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,0,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: ExportGifButton
        function ExportGifButtonPushed(app, event)

            if app.validFitFlag

                % Indicate busy
                app.SetLights([2,2,2,2,2,2,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                app.TextMessage('GIF export ...');

                try

                    try
                        gifExportDir = replace(app.mrdImportPath, "MRD", "Data");
                        k = strfind(gifExportDir,"Data");
                        fs = strfind(gifExportDir(k:end),filesep);
                        gifExportDir = gifExportDir(1:k+fs(2)-1);
                    catch
                        app.T2mappUIFigure.Visible = 'off';
                        app.gifExportPath = uigetdir(app.gifExportPath,'GIF export path');
                        app.T2mappUIFigure.Visible = 'on';
                        if ~ischar(app.gifExportPath)
                            ME = MException('T2map:userCancel','User cancel ...');
                            throw(ME);
                        end
                        gifExportDir = app.gifExportPath;
                    end
                    gifExportDir = strcat(gifExportDir,filesep,"GIF",filesep);

                    % Export to animated gif
                    app.TextMessage('GIF export ...');
                    app.TextMessage(strcat("Gif export directory = ",gifExportDir));

                    export_gif_t2(gifExportDir,app.t2map,app.m0map,app.r2map,app.tag,app.T2ScaleEditField.Value,app.t2cmap,app.m0cmap,app.r2cmap,app.AspectRatioViewField.Value,app.parameters,app.Rsquare.Value);

                    app.TabGroup2.SelectedTab = app.RawimagesTab;
                    app.TabGroup3.SelectedTab = app.M0R2Tab;
                    exportapp(app.T2mappUIFigure,strcat(gifExportDir,filesep,"M0map",app.tag,".png"));
                    app.TabGroup3.SelectedTab = app.T2mapTab;
                    exportapp(app.T2mappUIFigure,strcat(gifExportDir,filesep,"T2map",app.tag,".png"));
                   
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,2,1]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during GIF export ...');
   
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetLights([2,2,2,2,2,2,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
         
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('T2mapp.mlapp'));
                app.appDir = info.folder;
                rmpath(genpath(app.appDir));
                cd(app.oldDir);
            end
            
            % Exit
            app.delete;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.T2mappUIFigure = uifigure('Visible', 'off');
            app.T2mappUIFigure.Name = 'T2mapp';
            app.T2mappUIFigure.Icon = fullfile(pathToMLAPP, 'T2icon.png');
            app.T2mappUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @T2MappUIFigureWindowScrollWheel, true);
            app.T2T2mappingLabel = uilabel(app.T2mappUIFigure);
            app.T2T2mappingLabel.Text = 'T2 & T2* mapping';
            app.CreditsLabel = uilabel(app.T2mappUIFigure);
            app.CreditsLabel.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulpLogo = uiimage(app.T2mappUIFigure);
            app.MessageBox = uitextarea(app.T2mappUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Value = {'Messages ....'};
            app.BackgroundBox = uitextarea(app.T2mappUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.StatusImage = uiimage(app.T2mappUIFigure);
            app.statusLabel = uilabel(app.T2mappUIFigure);
            app.statusLabel.Text = 'status';
            app.MRSImage = uiimage(app.T2mappUIFigure);
            app.TabGroup1 = uitabgroup(app.T2mappUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDfileButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDfileButton.Enable = 'off';
            app.LoadMRDfileButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.RecoButton = uibutton(app.DashboardTab, 'push');
            app.RecoButton.ButtonPushedFcn = createCallbackFcn(app, @RecoButtonPushed, true);
            app.RecoButton.Enable = 'off';
            app.RecoButton.Text = 'Reco';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.ThresholdButton = uibutton(app.DashboardTab, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ThresholdButtonPushed, true);
            app.ThresholdButton.Enable = 'off';
            app.ThresholdButton.Text = 'Threshold';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.T2FitButton = uibutton(app.DashboardTab, 'push');
            app.T2FitButton.ButtonPushedFcn = createCallbackFcn(app, @T2FitButtonPushed, true);
            app.T2FitButton.Enable = 'off';
            app.T2FitButton.Text = 'T2 Fit';
            app.Lamp5 = uilamp(app.DashboardTab);
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.Lamp6 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Text = 'Exit';
            app.ExportDicomButton = uibutton(app.DashboardTab, 'push');
            app.ExportDicomButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDicomButtonPushed, true);
            app.ExportDicomButton.Enable = 'off';
            app.ExportDicomButton.Text = 'Export Dicom';
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.Lamp7 = uilamp(app.DashboardTab);
            app.ExportGifButton = uibutton(app.DashboardTab, 'push');
            app.ExportGifButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGifButtonPushed, true);
            app.ExportGifButton.Enable = 'off';
            app.ExportGifButton.Text = 'Export Gif';
            app.AbortFitButton = uibutton(app.DashboardTab, 'push');
            app.AbortFitButton.ButtonPushedFcn = createCallbackFcn(app, @AbortFitButtonPushed, true);
            app.AbortFitButton.Enable = 'off';
            app.AbortFitButton.Text = 'Abort';
            app.RegistrationButton = uibutton(app.DashboardTab, 'push');
            app.RegistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RegistrationButtonPushed, true);
            app.RegistrationButton.Enable = 'off';
            app.RegistrationButton.Text = 'Registration';
            app.Lamp3 = uilamp(app.DashboardTab);
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.MobaCheckBox = uicheckbox(app.DashboardTab);
            app.MobaCheckBox.Enable = 'off';
            app.MobaCheckBox.Text = '';
            app.ModelBasedLabel = uilabel(app.DashboardTab);
            app.ModelBasedLabel.Enable = 'off';
            app.ModelBasedLabel.Text = 'Model-based';
            app.CSrecoCheckBox = uicheckbox(app.DashboardTab);
            app.CSrecoCheckBox.Enable = 'off';
            app.CSrecoCheckBox.Text = '';
            app.CSrecoLabel = uilabel(app.DashboardTab);
            app.CSrecoLabel.Enable = 'off';
            app.CSrecoLabel.Text = 'CS reco';
            app.RegistrationDropDown = uidropdown(app.DashboardTab);
            app.RegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.RegistrationDropDown.Enable = 'off';
            app.RegistrationDropDown.Value = 'Affine';
            app.AbortRegButton = uibutton(app.DashboardTab, 'push');
            app.AbortRegButton.ButtonPushedFcn = createCallbackFcn(app, @AbortRegButtonPushed, true);
            app.AbortRegButton.Enable = 'off';
            app.AbortRegButton.Text = 'Abort';
            app.EstimatedRegTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedRegTimeViewField.Editable = 'off';
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.TabGroup3 = uitabgroup(app.T2mappUIFigure);
            app.T2mapTab = uitab(app.TabGroup3);
            app.T2mapTab.Title = 'T2-map';
            app.T2MapFig = uiaxes(app.T2mapTab);
            app.T2MapFig.XTick = [];
            app.T2MapFig.YTick = [];
            app.T2logPlot = uiaxes(app.T2mapTab);
            title(app.T2logPlot, 'Log')
            xlabel(app.T2logPlot, 'TE')
            ylabel(app.T2logPlot, 'log ( Signal )')
            app.T2linearPlot = uiaxes(app.T2mapTab);
            title(app.T2linearPlot, 'Linear')
            xlabel(app.T2linearPlot, 'TE')
            ylabel(app.T2linearPlot, 'Signal')
            app.T2MapLabel = uilabel(app.T2mapTab);
            app.T2MapLabel.Text = 'T2 map';
            app.ImageSelectionPanel2 = uipanel(app.T2mapTab);
            app.ImageSelectionPanel2.Title = 'Image Selection';
            app.SliceEditField_2Label = uilabel(app.ImageSelectionPanel2);
            app.SliceEditField_2Label.Text = 'Slice';
            app.SliceEditField_2Label_3 = uilabel(app.ImageSelectionPanel2);
            app.SliceEditField_2Label_3.Text = 'Dynamic';
            app.T2MapDynamicsSlider1 = uislider(app.ImageSelectionPanel2);
            app.T2MapDynamicsSlider1.Limits = [1 200];
            app.T2MapDynamicsSlider1.MajorTicks = [];
            app.T2MapDynamicsSlider1.ValueChangedFcn = createCallbackFcn(app, @T2MapSelectionSlider1ValueChanged, true);
            app.T2MapDynamicsSlider1.MinorTicks = [];
            app.T2MapDynamicsSlider1.Value = 1;
            app.T2MapDynamicsEditField1 = uieditfield(app.ImageSelectionPanel2, 'numeric');
            app.T2MapDynamicsEditField1.Limits = [1 Inf];
            app.T2MapDynamicsEditField1.ValueDisplayFormat = '%.0f';
            app.T2MapDynamicsEditField1.ValueChangedFcn = createCallbackFcn(app, @T2MapSelectionEditField1ValueChanged, true);
            app.T2MapDynamicsEditField1.Value = 1;
            app.T2MapSlicesSlider1 = uislider(app.ImageSelectionPanel2);
            app.T2MapSlicesSlider1.Limits = [1 200];
            app.T2MapSlicesSlider1.MajorTicks = [];
            app.T2MapSlicesSlider1.ValueChangedFcn = createCallbackFcn(app, @T2MapSelectionSlider1ValueChanged, true);
            app.T2MapSlicesSlider1.MinorTicks = [];
            app.T2MapSlicesSlider1.Value = 1;
            app.T2MapSlicesEditField1 = uieditfield(app.ImageSelectionPanel2, 'numeric');
            app.T2MapSlicesEditField1.Limits = [1 Inf];
            app.T2MapSlicesEditField1.ValueDisplayFormat = '%.0f';
            app.T2MapSlicesEditField1.ValueChangedFcn = createCallbackFcn(app, @T2MapSelectionEditField1ValueChanged, true);
            app.T2MapSlicesEditField1.Value = 1;
            app.ColorMapButtonGroup = uibuttongroup(app.T2mapTab);
            app.ColorMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroupSelectionChanged2, true);
            app.ColorMapButtonGroup.Title = 'Color Map';
            app.grayButton = uiradiobutton(app.ColorMapButtonGroup);
            app.grayButton.Text = 'gray';
            app.autumnButton = uiradiobutton(app.ColorMapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.winterButton = uiradiobutton(app.ColorMapButtonGroup);
            app.winterButton.Text = 'winter';
            app.parulaButton = uiradiobutton(app.ColorMapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.parulaButton.Value = true;
            app.hotButton = uiradiobutton(app.ColorMapButtonGroup);
            app.hotButton.Text = 'hot';
            app.T2ScaleEditField = uieditfield(app.ColorMapButtonGroup, 'numeric');
            app.T2ScaleEditField.Limits = [1 Inf];
            app.T2ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @T2ScaleEditFieldValueChanged, true);
            app.T2ScaleEditField.Value = 1;
            app.T2ScaleMaxLabel = uilabel(app.ColorMapButtonGroup);
            app.T2ScaleMaxLabel.Text = 'Max';
            app.T2ScaleAutoButton = uibutton(app.ColorMapButtonGroup, 'push');
            app.T2ScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @T2ScaleAutoButtonPushed, true);
            app.T2ScaleAutoButton.Text = 'Auto';
            app.T2Image = uiimage(app.T2mapTab);
            app.T2Image.ImageClickedFcn = createCallbackFcn(app, @T2ImageClicked, true);
            app.XEditFieldLabel = uilabel(app.T2mapTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.T2mapTab, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.T2mapTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.T2mapTab, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Editable = 'off';
            app.ROIbutton = uibutton(app.T2mapTab, 'push');
            app.ROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ROIbutton.Text = 'ROI';
            app.EraseButton = uibutton(app.T2mapTab, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Visible = 'off';
            app.EraseButton.Text = 'Erase';
            app.T2ViewField = uieditfield(app.T2mapTab, 'text');
            app.T2ViewField.Editable = 'off';
            app.T2msLabel = uilabel(app.T2mapTab);
            app.T2msLabel.Text = 'T2 (ms)';
            app.T2ViewField2 = uieditfield(app.T2mapTab, 'text');
            app.T2ViewField2.Editable = 'off';
            app.T2ViewField2.Visible = 'off';
            app.pixelValueLabel = uilabel(app.T2mapTab);
            app.pixelValueLabel.Text = 'Pixel value';
            app.ROIaveragedLabel = uilabel(app.T2mapTab);
            app.ROIaveragedLabel.Visible = 'off';
            app.ROIaveragedLabel.Text = 'ROI averaged';
            app.ExportRoiDataButton = uibutton(app.T2mapTab, 'push');
            app.ExportRoiDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRoiDataButtonPushed, true);
            app.ExportRoiDataButton.Text = 'Export';
            app.brushEditFieldLabel = uilabel(app.T2mapTab);
            app.brushEditFieldLabel.Visible = 'off';
            app.brushEditFieldLabel.Text = 'brush';
            app.BrushEditField = uieditfield(app.T2mapTab, 'numeric');
            app.BrushEditField.Limits = [1 5];
            app.BrushEditField.ValueDisplayFormat = '%.0f';
            app.BrushEditField.ValueChangedFcn = createCallbackFcn(app, @BrushEditFieldValueChanged, true);
            app.BrushEditField.Visible = 'off';
            app.BrushEditField.Value = 2;
            app.M0R2Tab = uitab(app.TabGroup3);
            app.M0R2Tab.Title = 'M0 & R^2';
            app.M0MapFig = uiaxes(app.M0R2Tab);
            app.M0MapFig.XTick = [];
            app.M0MapFig.YTick = [];
            app.R2MapFig = uiaxes(app.M0R2Tab);
            app.R2MapFig.XTick = [];
            app.R2MapFig.YTick = [];
            app.M0MapLabel = uilabel(app.M0R2Tab);
            app.M0MapLabel.Text = 'M0 map';
            app.R2MapLabel = uilabel(app.M0R2Tab);
            app.R2MapLabel.Text = 'R^2 map';
            app.ImageSelectorPanel2 = uipanel(app.M0R2Tab);
            app.ImageSelectorPanel2.Title = 'Image Selection';
            app.SliceEditField_2Label_2 = uilabel(app.ImageSelectorPanel2);
            app.SliceEditField_2Label_2.Text = 'Slice';
            app.T2MapDynamicsSlider2 = uislider(app.ImageSelectorPanel2);
            app.T2MapDynamicsSlider2.Limits = [1 200];
            app.T2MapDynamicsSlider2.MajorTicks = [];
            app.T2MapDynamicsSlider2.ValueChangedFcn = createCallbackFcn(app, @T2MapSelectionSlider2ValueChanged, true);
            app.T2MapDynamicsSlider2.MinorTicks = [];
            app.T2MapDynamicsSlider2.Value = 1;
            app.SliceEditField_2Label_4 = uilabel(app.ImageSelectorPanel2);
            app.SliceEditField_2Label_4.Text = 'Dynamic';
            app.T2MapDynamicsEditField2 = uieditfield(app.ImageSelectorPanel2, 'numeric');
            app.T2MapDynamicsEditField2.Limits = [1 Inf];
            app.T2MapDynamicsEditField2.ValueDisplayFormat = '%.0f';
            app.T2MapDynamicsEditField2.ValueChangedFcn = createCallbackFcn(app, @T2MapSelectionEditField2ValueChanged, true);
            app.T2MapDynamicsEditField2.Value = 1;
            app.T2MapSlicesSlider2 = uislider(app.ImageSelectorPanel2);
            app.T2MapSlicesSlider2.Limits = [1 200];
            app.T2MapSlicesSlider2.MajorTicks = [];
            app.T2MapSlicesSlider2.ValueChangedFcn = createCallbackFcn(app, @T2MapSelectionSlider2ValueChanged, true);
            app.T2MapSlicesSlider2.MinorTicks = [];
            app.T2MapSlicesSlider2.Value = 1;
            app.T2MapSlicesEditField2 = uieditfield(app.ImageSelectorPanel2, 'numeric');
            app.T2MapSlicesEditField2.Limits = [1 Inf];
            app.T2MapSlicesEditField2.ValueDisplayFormat = '%.0f';
            app.T2MapSlicesEditField2.ValueChangedFcn = createCallbackFcn(app, @T2MapSelectionEditField2ValueChanged, true);
            app.T2MapSlicesEditField2.Value = 1;
            app.ColorMapButtonGroup2 = uibuttongroup(app.M0R2Tab);
            app.ColorMapButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroup2SelectionChanged, true);
            app.ColorMapButtonGroup2.Title = 'Color Map';
            app.grayButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.grayButton2.Text = 'gray';
            app.grayButton2.Value = true;
            app.autumnButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.autumnButton2.Text = 'autumn';
            app.winterButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.winterButton2.Text = 'winter';
            app.parulaButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.parulaButton2.Text = 'parula';
            app.hotButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.hotButton2.Text = 'hot';
            app.ColorMapButtonGroup3 = uibuttongroup(app.M0R2Tab);
            app.ColorMapButtonGroup3.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroup3SelectionChanged, true);
            app.ColorMapButtonGroup3.Title = 'Color Map';
            app.grayButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.grayButton3.Text = 'gray';
            app.autumnButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.autumnButton3.Text = 'autumn';
            app.winterButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.winterButton3.Text = 'winter';
            app.parulaButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.parulaButton3.Text = 'parula';
            app.parulaButton3.Value = true;
            app.hotButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.hotButton3.Text = 'hot';
            app.M0Image = uiimage(app.M0R2Tab);
            app.M0Image.ImageClickedFcn = createCallbackFcn(app, @T2ImageClicked, true);
            app.R2Image = uiimage(app.M0R2Tab);
            app.ROIbutton2 = uibutton(app.M0R2Tab, 'push');
            app.ROIbutton2.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ROIbutton2.Text = 'ROI';
            app.EraseButton2 = uibutton(app.M0R2Tab, 'push');
            app.EraseButton2.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton2.Visible = 'off';
            app.EraseButton2.Text = 'Erase';
            app.brushEditField_2Label = uilabel(app.M0R2Tab);
            app.brushEditField_2Label.Visible = 'off';
            app.brushEditField_2Label.Text = 'brush';
            app.BrushEditField2 = uieditfield(app.M0R2Tab, 'numeric');
            app.BrushEditField2.Limits = [1 5];
            app.BrushEditField2.ValueDisplayFormat = '%.0f';
            app.BrushEditField2.ValueChangedFcn = createCallbackFcn(app, @BrushEditField2ValueChanged, true);
            app.BrushEditField2.Visible = 'off';
            app.BrushEditField2.Value = 2;
            app.TabGroup2 = uitabgroup(app.T2mappUIFigure);
            app.RawimagesTab = uitab(app.TabGroup2);
            app.RawimagesTab.Title = 'Raw images';
            app.RawDataFig = uiaxes(app.RawimagesTab);
            app.RawDataFig.XTick = [];
            app.RawDataFig.YTick = [];
            app.ImageLabel = uilabel(app.RawimagesTab);
            app.ImageLabel.Text = 'Image';
            app.ImageSelectionPanel = uipanel(app.RawimagesTab);
            app.ImageSelectionPanel.Title = 'Image Selection';
            app.SliceEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.EchoEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.EchoEditFieldLabel.Text = 'Echo';
            app.DynamicsLabel = uilabel(app.ImageSelectionPanel);
            app.DynamicsLabel.Text = 'Dynamic';
            app.DynamicsSlider = uislider(app.ImageSelectionPanel);
            app.DynamicsSlider.Limits = [1 200];
            app.DynamicsSlider.MajorTicks = [];
            app.DynamicsSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionSliderValueChanged, true);
            app.DynamicsSlider.MinorTicks = [];
            app.DynamicsSlider.Value = 1;
            app.DynamicsEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.DynamicsEditField.Limits = [1 Inf];
            app.DynamicsEditField.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionEditFieldValueChanged, true);
            app.DynamicsEditField.Value = 1;
            app.EchoesSlider = uislider(app.ImageSelectionPanel);
            app.EchoesSlider.Limits = [1 200];
            app.EchoesSlider.MajorTicks = [];
            app.EchoesSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionSliderValueChanged, true);
            app.EchoesSlider.MinorTicks = [];
            app.EchoesSlider.Value = 1;
            app.EchoesEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.EchoesEditField.Limits = [1 Inf];
            app.EchoesEditField.ValueDisplayFormat = '%.0f';
            app.EchoesEditField.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionEditFieldValueChanged, true);
            app.EchoesEditField.Value = 1;
            app.SlicesSlider = uislider(app.ImageSelectionPanel);
            app.SlicesSlider.Limits = [1 200];
            app.SlicesSlider.MajorTicks = [];
            app.SlicesSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionSliderValueChanged, true);
            app.SlicesSlider.MinorTicks = [];
            app.SlicesSlider.Value = 1;
            app.SlicesEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.SlicesEditField.Limits = [1 Inf];
            app.SlicesEditField.ValueDisplayFormat = '%.0f';
            app.SlicesEditField.ValueChangedFcn = createCallbackFcn(app, @ImageSelectionEditFieldValueChanged, true);
            app.SlicesEditField.Value = 1;
            app.SegmentationPanel = uipanel(app.RawimagesTab);
            app.SegmentationPanel.Title = 'Segmentation';
            app.ThresholdSlider = uislider(app.SegmentationPanel);
            app.ThresholdSlider.Limits = [1 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.SegmentationPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.SegmentationPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.SegmentationPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.SegmentationPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.ThresholdEditFieldLabel = uilabel(app.SegmentationPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.RawImage = uiimage(app.RawimagesTab);
            app.WindowSlider = uislider(app.RawimagesTab);
            app.WindowSlider.Limits = [0 32768];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [];
            app.WindowSlider.Value = 16384;
            app.LevelSlider = uislider(app.RawimagesTab);
            app.LevelSlider.Limits = [0 32768];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [];
            app.LevelSlider.Value = 16384;
            app.AutoLWButton = uibutton(app.RawimagesTab, 'state');
            app.AutoLWButton.ValueChangedFcn = createCallbackFcn(app, @AutoLWButtonValueChanged, true);
            app.AutoLWButton.Text = 'Auto';
            app.AutoLWButton.Value = true;
            app.WEditFieldLabel = uilabel(app.RawimagesTab);
            app.WEditFieldLabel.Text = 'W';
            app.WindowEditField = uieditfield(app.RawimagesTab, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.LEditFieldLabel = uilabel(app.RawimagesTab);
            app.LEditFieldLabel.Text = 'L';
            app.LevelEditField = uieditfield(app.RawimagesTab, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.RecoparametersTab = uitab(app.TabGroup2);
            app.RecoparametersTab.Title = 'Reco parameters';
            app.DeNoiseCheckBox = uicheckbox(app.RecoparametersTab);
            app.DeNoiseCheckBox.Enable = 'off';
            app.DeNoiseCheckBox.Text = '';
            app.DeNoiseLabel = uilabel(app.RecoparametersTab);
            app.DeNoiseLabel.Enable = 'off';
            app.DeNoiseLabel.Text = 'deNoise';
            app.DeNoiseWindowEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.DeNoiseWindowEditField.Limits = [2 64];
            app.DeNoiseWindowEditField.RoundFractionalValues = 'on';
            app.DeNoiseWindowEditField.ValueDisplayFormat = '%.0f';
            app.DeNoiseWindowEditField.Enable = 'off';
            app.DeNoiseWindowEditField.Value = 8;
            app.DeRingCheckBox = uicheckbox(app.RecoparametersTab);
            app.DeRingCheckBox.Enable = 'off';
            app.DeRingCheckBox.Text = '';
            app.DeRingLabel = uilabel(app.RecoparametersTab);
            app.DeRingLabel.Enable = 'off';
            app.DeRingLabel.Text = 'deRing';
            app.FilteringLabel = uilabel(app.RecoparametersTab);
            app.FilteringLabel.Text = 'Filtering';
            app.TVteEditFieldLabel = uilabel(app.RecoparametersTab);
            app.TVteEditFieldLabel.Tooltip = {'Total Variation constraint in TE dimension'};
            app.TVteEditFieldLabel.Text = 'TVte';
            app.TVdynEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.TVdynEditField.Limits = [0 20];
            app.TVdynEditField.ValueDisplayFormat = '%.4f';
            app.TVdynEditField.Enable = 'off';
            app.TVdynEditField.Tooltip = {'Total Variation constraint in dynamic dimension'};
            app.TVteEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.TVteEditField.Limits = [0 20];
            app.TVteEditField.ValueDisplayFormat = '%.4f';
            app.TVteEditField.Enable = 'off';
            app.TVteEditField.Tooltip = {'Total Variation constraint in TE dimension'};
            app.LLRxyzEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.LLRxyzEditField.Limits = [0 20];
            app.LLRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LLRxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Tooltip = {'Locally Low-Rank in spatial domain'};
            app.LLRxyzEditField.Value = 0.001;
            app.TVxyzEditFieldLabel = uilabel(app.RecoparametersTab);
            app.TVxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVxyzEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.TVxyzEditField.Limits = [0 20];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.Enable = 'off';
            app.TVxyzEditField.Tooltip = {'Total Variation constraint in spatial domain'};
            app.TVxyzEditField.Value = 0.001;
            app.WVxyzEditFieldLabel = uilabel(app.RecoparametersTab);
            app.WVxyzEditFieldLabel.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.WVxyzEditField.Limits = [0 20];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.Enable = 'off';
            app.WVxyzEditField.Tooltip = {'Wavelet L1 regularization in spatial domain'};
            app.WVxyzEditField.Value = 0.001;
            app.TVdynEditFieldLabel = uilabel(app.RecoparametersTab);
            app.TVdynEditFieldLabel.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.TVdynEditFieldLabel.Text = 'TVdyn';
            app.LLRxyzEditFieldLabel = uilabel(app.RecoparametersTab);
            app.LLRxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.CSregularizationLabel = uilabel(app.RecoparametersTab);
            app.CSregularizationLabel.Text = 'CS regularization';
            app.FitparametersTab = uitab(app.TabGroup2);
            app.FitparametersTab.Title = 'Fit parameters';
            app.SliceselectionPanel = uipanel(app.FitparametersTab);
            app.SliceselectionPanel.Title = 'Slice selection';
            app.FirstsliceLabel = uilabel(app.SliceselectionPanel);
            app.FirstsliceLabel.Text = 'First slice';
            app.FirstSliceEditField = uieditfield(app.SliceselectionPanel, 'numeric');
            app.FirstSliceEditField.Limits = [1 200];
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceRangeEditFieldValueChanged, true);
            app.FirstSliceEditField.Enable = 'off';
            app.FirstSliceEditField.Value = 1;
            app.LastsliceLabel = uilabel(app.SliceselectionPanel);
            app.LastsliceLabel.Text = 'Last slice';
            app.LastSliceEditField = uieditfield(app.SliceselectionPanel, 'numeric');
            app.LastSliceEditField.Limits = [1 200];
            app.LastSliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceRangeEditFieldValueChanged, true);
            app.LastSliceEditField.Enable = 'off';
            app.LastSliceEditField.Value = 1;
            app.GoodnessoffitPanel = uipanel(app.FitparametersTab);
            app.GoodnessoffitPanel.Title = 'Goodness of fit';
            app.MinRsquareLabel = uilabel(app.GoodnessoffitPanel);
            app.MinRsquareLabel.Text = 'Min R-square';
            app.Rsquare = uieditfield(app.GoodnessoffitPanel, 'numeric');
            app.Rsquare.Limits = [0 1];
            app.Rsquare.ValueDisplayFormat = '%.2f';
            app.Rsquare.Enable = 'off';
            app.Rsquare.Value = 0.8;
            app.EchoselectionPanel = uipanel(app.FitparametersTab);
            app.EchoselectionPanel.Title = 'Echo selection';
            app.CheckBox_1 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_2 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_17.Value = true;
            app.CheckBox_18 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_18.Value = true;
            app.CheckBox_19 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_19.Value = true;
            app.CheckBox_20 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_20.Value = true;
            app.CheckBox_21 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_21.Value = true;
            app.CheckBox_22 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_22.Value = true;
            app.CheckBox_23 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_23.Value = true;
            app.CheckBox_24 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_24.Value = true;
            app.CheckBox_25 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_25.Value = true;
            app.CheckBox_26 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_26.Value = true;
            app.CheckBox_27 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_27.Value = true;
            app.CheckBox_28 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_28.Value = true;
            app.CheckBox_29 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_29.Value = true;
            app.CheckBox_30 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_30.Value = true;
            app.CheckBox_31 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_31.Value = true;
            app.CheckBox_32 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_32.Text = '32';
            app.CheckBox_32.Value = true;
            app.CheckBox_33 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_33.Text = '33';
            app.CheckBox_33.Value = true;
            app.CheckBox_34 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_34.Text = '34';
            app.CheckBox_34.Value = true;
            app.CheckBox_35 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_35.Text = '35';
            app.CheckBox_35.Value = true;
            app.CheckBox_36 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_36.Text = '36';
            app.CheckBox_36.Value = true;
            app.CheckBox_37 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_37.Text = '37';
            app.CheckBox_37.Value = true;
            app.CheckBox_38 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_38.Text = '38';
            app.CheckBox_38.Value = true;
            app.CheckBox_39 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_39.Text = '39';
            app.CheckBox_39.Value = true;
            app.CheckBox_40 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_40.Text = '40';
            app.CheckBox_40.Value = true;
            app.CheckBox_41 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_41.Text = '41';
            app.CheckBox_41.Value = true;
            app.CheckBox_42 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_42.Text = '42';
            app.CheckBox_42.Value = true;
            app.CheckBox_43 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_43.Text = '43';
            app.CheckBox_43.Value = true;
            app.CheckBox_44 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_44.Text = '44';
            app.CheckBox_44.Value = true;
            app.CheckBox_45 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_45.Text = '45';
            app.CheckBox_45.Value = true;
            app.CheckBox_46 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_46.Text = '46';
            app.CheckBox_46.Value = true;
            app.CheckBox_47 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_47.Text = '47';
            app.CheckBox_47.Value = true;
            app.CheckBox_48 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_48.Text = '48';
            app.CheckBox_48.Value = true;
            app.CheckBox_49 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_49.Text = '49';
            app.CheckBox_49.Value = true;
            app.CheckBox_50 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_50.Text = '50';
            app.CheckBox_50.Value = true;
            app.CheckBox_51 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_51.Text = '51';
            app.CheckBox_51.Value = true;
            app.CheckBox_52 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_52.Text = '52';
            app.CheckBox_52.Value = true;
            app.CheckBox_53 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_53.Text = '53';
            app.CheckBox_53.Value = true;
            app.CheckBox_54 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_54.Text = '54';
            app.CheckBox_54.Value = true;
            app.CheckBox_55 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_55.Text = '55';
            app.CheckBox_55.Value = true;
            app.CheckBox_56 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_56.Text = '56';
            app.CheckBox_56.Value = true;
            app.CheckBox_57 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_57.Text = '57';
            app.CheckBox_57.Value = true;
            app.CheckBox_58 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_58.Text = '58';
            app.CheckBox_58.Value = true;
            app.CheckBox_59 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_59.Text = '59';
            app.CheckBox_59.Value = true;
            app.CheckBox_60 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_60.Text = '60';
            app.CheckBox_60.Value = true;
            app.CheckBox_61 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_61.Text = '61';
            app.CheckBox_61.Value = true;
            app.CheckBox_62 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_62.Text = '62';
            app.CheckBox_62.Value = true;
            app.CheckBox_63 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_63.Text = '63';
            app.CheckBox_63.Value = true;
            app.CheckBox_64 = uicheckbox(app.EchoselectionPanel);
            app.CheckBox_64.Text = '64';
            app.CheckBox_64.Value = true;
            app.DynamicsSelectionPanel = uipanel(app.FitparametersTab);
            app.DynamicsSelectionPanel.Title = 'Dynamics selection';
            app.FirstsliceLabel_2 = uilabel(app.DynamicsSelectionPanel);
            app.FirstsliceLabel_2.Text = 'First dynamic';
            app.FirstDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.FirstDynamicEditField.Limits = [1 500];
            app.FirstDynamicEditField.ValueDisplayFormat = '%.0f';
            app.FirstDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicRangeEditFieldValueChanged, true);
            app.FirstDynamicEditField.Enable = 'off';
            app.FirstDynamicEditField.Value = 1;
            app.LastsliceLabel_2 = uilabel(app.DynamicsSelectionPanel);
            app.LastsliceLabel_2.Text = 'Last dynamic';
            app.LastDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.LastDynamicEditField.Limits = [1 500];
            app.LastDynamicEditField.ValueDisplayFormat = '%.0f';
            app.LastDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @DynamicRangeEditFieldValueChanged, true);
            app.LastDynamicEditField.Enable = 'off';
            app.LastDynamicEditField.Value = 1;
            app.TabGroup4 = uitabgroup(app.T2mappUIFigure);
            app.SequenceparametersTab = uitab(app.TabGroup4);
            app.SequenceparametersTab.Title = 'Sequence parameters';
            app.TRmsEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.TRmsEditFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.Editable = 'off';
            app.TE0msLabel = uilabel(app.SequenceparametersTab);
            app.TE0msLabel.Text = 'TE0 (ms)';
            app.TE0EditField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.TE0EditField.Limits = [0 Inf];
            app.TE0EditField.ValueChangedFcn = createCallbackFcn(app, @TEViewFieldValueChanged, true);
            app.dimxEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.dimxEditFieldLabel.Text = 'dimx';
            app.dimxViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.dimxViewField.Limits = [0 Inf];
            app.dimxViewField.ValueDisplayFormat = '%.0f';
            app.dimxViewField.Editable = 'off';
            app.dimyEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.dimyEditFieldLabel.Text = 'dimy';
            app.dimyViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.dimyViewField.Limits = [0 Inf];
            app.dimyViewField.ValueDisplayFormat = '%.0f';
            app.dimyViewField.Editable = 'off';
            app.slicesLabel = uilabel(app.SequenceparametersTab);
            app.slicesLabel.Text = '# slices';
            app.SlicesViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.EchoesLabel = uilabel(app.SequenceparametersTab);
            app.EchoesLabel.Text = '# echoes';
            app.EchoesViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.EchoesViewField.Limits = [0 Inf];
            app.EchoesViewField.Editable = 'off';
            app.FOVmmEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.FOVmmEditFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.SlicethicknessmmLabel = uilabel(app.SequenceparametersTab);
            app.SlicethicknessmmLabel.Text = 'Slice thickness (mm)';
            app.SLTViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.SLTViewField.Limits = [0 Inf];
            app.SLTViewField.Editable = 'off';
            app.AspectratioLabel = uilabel(app.SequenceparametersTab);
            app.AspectratioLabel.Text = 'Aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Value = 1;
            app.DatafileEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.DatafileEditFieldLabel.Text = 'Datafile';
            app.DatafileEditField = uieditfield(app.SequenceparametersTab, 'text');
            app.DatafileEditField.Editable = 'off';
            app.ESPmsLabel = uilabel(app.SequenceparametersTab);
            app.ESPmsLabel.Text = 'ESP (ms)';
            app.ESPEditField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.ESPEditField.Limits = [0 Inf];
            app.ESPEditField.ValueChangedFcn = createCallbackFcn(app, @TEViewFieldValueChanged, true);
            app.EchoesLabel_2 = uilabel(app.SequenceparametersTab);
            app.EchoesLabel_2.Text = '# dynamics';
            app.DynamicsViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.DynamicsViewField.Limits = [0 Inf];
            app.DynamicsViewField.Editable = 'off';
            % Show the figure after all components are created
            app.T2mappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T2mapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.T2mappUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.T2mappUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T2mappUIFigure)
        end
    end
end